classdef Version5_MobileRobotControlGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MobileRobotGUIUIFigure          matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        RightPanel                      matlab.ui.container.Panel
        Image6                          matlab.ui.control.Image
        Image5                          matlab.ui.control.Image
        TabGroup                        matlab.ui.container.TabGroup
        SerialPortTab                   matlab.ui.container.Tab
        DisconnectfromCOMPortButton     matlab.ui.control.Button
        ConnectLamp                     matlab.ui.control.Lamp
        COMPortsListBox                 matlab.ui.control.ListBox
        COMPortsListBoxLabel            matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        ConnecttoCOMPortButton          matlab.ui.control.Button
        SearchforCOMPortsButton         matlab.ui.control.Button
        ManualModeTab                   matlab.ui.container.Tab
        ManualControlSwitch             matlab.ui.control.Switch
        ManualControlSwitchLabel        matlab.ui.control.Label
        RightWheelOmegaGauge            matlab.ui.control.Gauge
        RightWheelradsLabel             matlab.ui.control.Label
        LeftWheelOmegaGauge             matlab.ui.control.Gauge
        LeftWheelradsLabel              matlab.ui.control.Label
        KeyboardControlLamp             matlab.ui.control.Lamp
        KeyboardControlLampLabel        matlab.ui.control.Label
        AutonomousModeTab               matlab.ui.container.Tab
        DisableAutonomousControlButton  matlab.ui.control.Button
        ActivateAutonomousControlButton  matlab.ui.control.Button
        CurrPoseX                       matlab.ui.control.NumericEditField
        XEditField_2Label               matlab.ui.control.Label
        CurrPoseY                       matlab.ui.control.NumericEditField
        YEditField_2Label               matlab.ui.control.Label
        CurrPoseTheta                   matlab.ui.control.NumericEditField
        thetaLabel                      matlab.ui.control.Label
        CurrentPoseLabel                matlab.ui.control.Label
        TrajectoryStatusLamp            matlab.ui.control.Lamp
        TrajectoryStatusLampLabel       matlab.ui.control.Label
        GoalPoseLabel                   matlab.ui.control.Label
        GoalPoseTheta                   matlab.ui.control.NumericEditField
        ThetaLabel                      matlab.ui.control.Label
        GoalPoseY                       matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        GoalPoseX                       matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        ExecuteTrajectoryButton         matlab.ui.control.Button
        PlanTrajectoryButton            matlab.ui.control.Button
        DebugTab                        matlab.ui.container.Tab
        DebugUT3                        matlab.ui.control.NumericEditField
        XEditField_2Label_7             matlab.ui.control.Label
        DebugUT2                        matlab.ui.control.NumericEditField
        XEditField_2Label_6             matlab.ui.control.Label
        DebugUT1                        matlab.ui.control.NumericEditField
        XEditField_2Label_5             matlab.ui.control.Label
        DebugRightMotor                 matlab.ui.control.NumericEditField
        XEditField_2Label_4             matlab.ui.control.Label
        DebugLeftMotor                  matlab.ui.control.NumericEditField
        XEditField_2Label_3             matlab.ui.control.Label
        DebugUTLabel                    matlab.ui.control.Label
        DebugMotorLabel                 matlab.ui.control.Label
        DebugPoseTheta                  matlab.ui.control.NumericEditField
        thetaLabel_2                    matlab.ui.control.Label
        DebugPoseY                      matlab.ui.control.NumericEditField
        YEditField_2Label_2             matlab.ui.control.Label
        DebugPoseX                      matlab.ui.control.NumericEditField
        XEditField_2Label_2             matlab.ui.control.Label
        DebugPoseLabel                  matlab.ui.control.Label
        BytesAvailableEditField         matlab.ui.control.NumericEditField
        BytesAvailableEditFieldLabel    matlab.ui.control.Label
        ControlPlot                     matlab.ui.control.UIAxes
        StatePlot                       matlab.ui.control.UIAxes
        LeftPanel                       matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        Image4                          matlab.ui.control.Image
        STOPButton                      matlab.ui.control.Button
        ResetOccupancyGridButton        matlab.ui.control.Button
        LoadOccupancyGridButton         matlab.ui.control.Button
        RobotEnvironmentPlot            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        readSerialTimerObject % timer object for reading serial
        writeAutomatedTimerObject % timer object for writing to serial
        plotDataTimerObject % timer object for plotting data

        % audio objects
        soundData
        soundRate 
        
        robotLength = 0.2
        robotWidth = 0.2
        
        driveMode = 2 % drive mode for the robot. 0 for autonomous, 1 for manual

        path % global variable for path generated
        occupancyGrid = binaryOccupancyMap(5,5,10);% global variable for occupancy grid
        omegaL = 0 % global variable for angular velocity of left wheel
        omegaR = 0 % global variable for angular velocity of right wheel
        optimalInputs % global array for OL control inputs from MPC
        progressbar

        comPortList
        selectedCOMPortIndex 
        selectedCOMPort % global variable for the selected COM Port
        SerialObject % serial object to be connected to

        DataPkg % data that will be written to serial port
        MPCInputs
        MPCIndex % index into MPC Inputs array

        estimatedTravelTime % estimation of how long it will take robot to traverse path
        
        xActual = 1 % state estimation calc for x
        yActual = 2.5 % state estimation calc for y
        thetaActual = 0 % state estimation calc for theta
        measuredOmegaR = 0 % received pulse data from the mobile robot to do calculations with
        measuredOmegaL = 0
        ultrasonic1
        ultrasonic2
        ultrasonic3
        count

        plotTime = 0 % value that will be plotted as time in our plots

        OccupancyGridWidth = 5; % Robot environment occupancy grid width

        xPlotData
        yPlotData
        tPlotData
        xPlanned
        yPlanned
        tPlanned
        omega_R_PlotData
        omega_R_Planned
        omega_L_PlotData
        omega_L_Planned
        startPoseFill
        startPoseLine
        goalPoseFill
        goalPoseLine
        currentPoseFill
        currentPoseLine
        robotTrajectoryPlot


    end
    
    methods (Access = private)
        
        % Callback function that reads serial port 
        function readSerialCB(app)
            app.readSerialTimerObject = timer('ExecutionMode', 'fixedRate','Name','ReadSerial','BusyMode','drop', 'Period', 0.01, 'TimerFcn', @readSerial);
            
            function readSerial(~,~)
                app.BytesAvailableEditField.Value = app.SerialObject.NumBytesAvailable;
                if app.SerialObject.NumBytesAvailable >= 36
                    odometryData = read(app.SerialObject, 6, 'int16');
                    app.xActual = odometryData(1)/1000;
                    app.yActual = odometryData(3)/1000;
                    app.thetaActual = wrapToPi(odometryData(5)/1000);

                    app.DebugPoseX.Value = app.xActual;
                    app.CurrPoseX.Value = app.xActual;
                    app.DebugPoseY.Value = app.yActual;
                    app.CurrPoseY.Value = app.yActual;
                    app.DebugPoseTheta.Value = app.thetaActual;
                    app.CurrPoseTheta.Value = app.thetaActual;
        
                        
                    pulseData = read(app.SerialObject, 2, 'int8');
                    app.measuredOmegaR = (2*pi*100*pulseData(1))/1120;
                    app.measuredOmegaL = (2*pi*100*pulseData(2))/1120;

                    app.DebugRightMotor.Value = app.measuredOmegaR;
                    app.DebugLeftMotor.Value = app.measuredOmegaL;
                       
                    ultrasonicData = read(app.SerialObject, 22, 'uint8');
                    app.ultrasonic1 = ultrasonicData(1);
                    app.ultrasonic2 = ultrasonicData(2);
                    app.ultrasonic3 = ultrasonicData(3);
                    app.count = ultrasonicData(4);
                    app.DebugUT1.Value = app.ultrasonic1;
                    app.DebugUT2.Value = app.ultrasonic2;
                    app.DebugUT3.Value = app.ultrasonic3;
                end
            end
        end
           
        % Callback function that writes automated data
        function writeAutomatedDataCB(app)

            app.writeAutomatedTimerObject = timer('ExecutionMode', 'fixedDelay','Name','WriteSerial','BusyMode','queue', 'Period', 0.05, 'TimerFcn', @writeUpdate,'StopFcn',@writeUpdateEnd);
            function writeUpdate(~,~)

                % Extract the values of MPCInputs at each time step [right,
                % left] and sets DataPkg equal to it. 
                app.DataPkg = [app.MPCInputs(1,1), app.MPCInputs(2,1)];
                disp(app.DataPkg);

                write(app.SerialObject, app.DataPkg, "int8")
                                
                app.MPCInputs(:,1) = [];
                %app.DataPkg = [0,0];
                %write(app.Serialobject, app.DataPkg, "int8")
                %stop(app.writeAutomatedTimerObject);
                %stop(app.plotDataTimerObject);
                
            end

            function writeUpdateEnd(~,~)
                % Stop the Robot
                write(app.SerialObject, [0,0], "int8");

                % Clear Previous MPC inputs
                app.MPCInputs = [];

                % Disable Trajectory Execution
                app.ExecuteTrajectoryButton.Enable ="off";
                app.TrajectoryStatusLamp.Color = 'r';
                
                % Enable Path planning button
                app.PlanTrajectoryButton.Enable = "on";

            end

        end

        % Callback function that plots 
        function plotUpdateCB(app)

            app.plotDataTimerObject = timer('ExecutionMode', 'fixedDelay','Name','Plot','BusyMode','drop', 'Period', 0.1, 'TimerFcn', @plotUpdate);
            
            function plotUpdate(~,~)
                
                    % Update Control Plot
                    tSeries = [get(app.omega_L_PlotData,'XData') app.plotTime];
                    ySeries = [get(app.omega_L_PlotData,'YData') app.measuredOmegaL];
                    set(app.omega_L_PlotData,'XData',tSeries,'YData',ySeries);
                
                    ySeries = [get(app.omega_R_PlotData,'YData') app.measuredOmegaR];
                    set(app.omega_R_PlotData,'XData',tSeries,'YData',ySeries);

                    % Update State Plot
                    ySeries = [get(app.xPlotData,'YData') app.xActual];
                    set(app.xPlotData,'XData',tSeries,'YData',ySeries);

                    ySeries = [get(app.yPlotData,'YData') app.yActual];
                    set(app.yPlotData,'XData',tSeries,'YData',ySeries);

                    ySeries = [get(app.tPlotData,'YData') app.thetaActual];
                    set(app.tPlotData,'XData',tSeries,'YData',ySeries);
                %try
                % 
                    % Updating X Y Trajectory
                    hold(app.RobotEnvironmentPlot, "off");
                    if app.driveMode == 1
                        UpdateOccupancyGrid(app);
                    end
                    hold(app.RobotEnvironmentPlot, "on");
          
                    [newX, newY] = GenerateRobot(app.xActual, app.yActual, app.robotLength, app.robotWidth, app.thetaActual);
        
                    fill(app.RobotEnvironmentPlot, newX, newY,'b');
                    plot(app.RobotEnvironmentPlot, [app.xActual app.xActual + app.robotLength*cos(app.thetaActual)],[app.yActual app.yActual + app.robotLength*sin(app.thetaActual)],"Color",'k','LineWidth',2);
                    app.plotTime = app.plotTime + 0.1;
                %catch
                %end
            end

        end

        function pathPlanning(app)
            % load occupancy grid
            cla(app.RobotEnvironmentPlot);
            hold(app.RobotEnvironmentPlot,"off");
            show(app.occupancyGrid,'Parent',app.RobotEnvironmentPlot);


            map = copy(app.occupancyGrid);
            inflate(map,0.2);
            
            %Create a mobileRobotPRM object with a binary occupancy map and specify the
            % maximum number of nodes. Specify the maximum distance between the two
            % connected notes
            
            % Increasing this number decreases noise in our trajectory planning but will increase computation time
            numnodes = 2500;
            planner = mobileRobotPRM(map, numnodes);
            
            % Adjust this value to create more or less control points for Bezier.
            % Smaller value for more points, greater value for less points
            planner.ConnectionDistance = 0.5; 
            
            startPosition = [app.xActual app.yActual];
            
            goalPosition = [app.GoalPoseX.Value, app.GoalPoseY.Value];

            %Find a path between the start and goal positions and generates waypoints
            %in between
            waypoints = findpath(planner,startPosition, goalPosition);
            
            waypoints = transpose(waypoints);
            % Find out how long the path length is and calculate the
            % amount of time it will take to traverse the length to
            % update the end condition of timePoints

            xValues = waypoints(1,:);
            yValues = waypoints(2,:);
            
            totalDistance = 0;
            for i = 1:1:length(xValues)-1
                tempDistance = sqrt(((xValues(i+1)-xValues(i))^2)+((yValues(i+1)-yValues(i))^2));
                totalDistance = totalDistance + tempDistance;
                
            end
                        
            robotAvgSpeed = 0.70;
            endTimePoint = totalDistance/robotAvgSpeed;

            app.estimatedTravelTime = endTimePoint;
            timePoints = [0 endTimePoint];
            timeVector = 0:0.1:endTimePoint;

            [q, qd, qdd, pp] = bsplinepolytraj(waypoints,timePoints,timeVector);
            thetaValues = zeros(1,size(q,2));

            for j = 1:1:size(q,2)-1
                thetaValues(j) = atan2((q(2,j+1)-q(2,j)),(q(1,j+1)-q(1,j)));
            end
            thetaValues(end) = app.GoalPoseTheta.Value;
            %app.GoalPoseTheta.Value = thetaValues(end);
            q = [q;thetaValues];
            app.path = q;
            try
                disp(q);
            catch
            end
        end

        function MPCPlanner(app)
            % MPC Function setups
            
            function y = UnicycleOutput(x,u,params)
                y = x; % Full State Feedback
            end

            function z_dot = UnicycleContinuous(z,u)
                DistanceScalingFactor = 1.25;
                heading_scaling_factor = 1.16;
                % Robot Constant Parameters
                r_wheel = 0.030; % Wheel Radius [m]
                d_wheel = 0.200/heading_scaling_factor; % Distance Between Wheels [m]
                
                % States
                x = z(1);       % Vehicle X Position    [m]
                y = z(2);       % Vehicle Y Position    [m]
                theta = z(3);   % Vehicle Heading       [rad]
                
                % Inputs
                w_l = u(1);     % Left Wheel Angular Velocity   [rad/s]
                w_r = u(2);     % Left Wheel Angular Velocity   [rad/s]
                
                % Continuous Dynamics
                z_dot = [   (r_wheel/2*w_r + r_wheel/2*w_l)*cos(theta);...
                            (r_wheel/2*w_r + r_wheel/2*w_l)*sin(theta);...
                            r_wheel*w_r/d_wheel - r_wheel*w_l/d_wheel];
            end

            function zk1 = UnicycleDiscrete(zk,uk,Ts)
                M = 10;
                delta = Ts/M;
                zk1 = zk;
                for ct = 1:M
                    zk1 = zk1 + delta*UnicycleContinuous(zk1,uk);
                end
            end

            % MPC Controller Creation and Setup
            % Set Number of States (nx), number of outputs (ny), and inputs or Manipulated Variables (nu)
            nx = 3;
            ny = 3; % Assuming full state feedback
            nu = 2;
            
            % Create a non-linear MPC controller
            MPCController = nlmpc(nx,ny,nu);
            
            % Set Discrete Sampling Time in seconds
            Ts = 0.1;
            MPCController.Ts = Ts;
            
            % Set Prediction Horizon and Control Horizon
            MPCController.PredictionHorizon = 10;
            MPCController.ControlHorizon = 8;
            
            % Set the discrete time system model for Unicycle Robot
            MPCController.Model.StateFcn = "UnicycleDiscrete";
            MPCController.Model.IsContinuousTime = false;
            MPCController.Model.NumberOfParameters = 1;
            
            % Set the system output model for the unicycle robot
            MPCController.Model.OutputFcn = "UnicycleOutput";
            
            % Provide the system Jacobian
            % MPCController.Jacobian.OutputFcn = @(x,u,Ts) [1 0 -u(1)*sin(x(3)); 0 1 u(1)*sin(x(3)); 0 0 1];
            
            % Define MPC Cost
            MPCController.Weights.OutputVariables = [10 10 10]; % State Error Cost
            MPCController.Weights.ManipulatedVariables = [0.1 0.1]; % Input Cost
            MPCController.Weights.ManipulatedVariablesRate = [0.2 0.2]; % Input Rate Cost
            
            % Define Constraint on State Variables
            MPCController.OV(1).Min = -10;
            MPCController.OV(1).Max = 10;
            
            MPCController.OV(2).Min = -10;
            MPCController.OV(2).Max = 10;
            
            % Define Constraints on Actuator Inputs
            MPCController.MV(1).Min = -20;
            MPCController.MV(1).Max = 40;
            
            MPCController.MV(2).Min = -20;
            MPCController.MV(2).Max = 40;
            
            % Define Constraints on Actuator Input Rate of Change
            MPCController.MV(1).RateMin = -5;
            MPCController.MV(1).RateMax = 5;
            
            MPCController.MV(2).RateMin = -5;
            MPCController.MV(2).RateMax = 5;

            % MPC Controller Validation

            x0 = [app.xActual; app.yActual; wrapToPi(app.thetaActual)];
            u0 = [0;0];
            validateFcns(MPCController, x0,u0,[],{Ts});
            try
                x_t = app.path(1,end);
                y_t = app.path(2,end);
                theta_t = app.path(3,end);

                terminal_path = zeros(3,20);
                terminal_path(1,:) = x_t;
                terminal_path(2,:) = y_t;
                terminal_path(3,:) = theta_t;

                q = [app.path terminal_path];
                %q = q(:,1:4:end); % Downsample q
                xref = zeros(nx,size(q,2));
                q = q';
                
                xref = zeros(size(q,1),3);
                xref(:,1) = q(:,1);
                xref(:,2) = q(:,2);
                xref(:,3) = q(:,3);
                t = 0:Ts:Ts*(size(q,1)-1);
                %t = 0:Ts:Ts*(size(q,1)-1);
                t = t';
            catch
                error("Failed to generate MPC.");
            end
            % Simulate MPC Controller
            options = nlmpcmoveopt;
            options.Parameters = {Ts};
            x = x0;
            u = u0;
            xHistory = x;
            uHistory = u;
            trajectorylookahead = 10;
            xref = [xref; xref(end,:); xref(end,:); xref(end,:); xref(end,:); xref(end,:); xref(end,:); xref(end,:); xref(end,:); xref(end,:); xref(end,:)];
            
            [coreData, onlineData] = getCodeGenerationData(MPCController, x0, u0, {Ts});
            
            tic;
            
            for k = 1:length(t)
                % Update state reference
                onlineData.ref = xref(k:k+trajectorylookahead,:);
            
                % Compute MPC
                [u, onlineData] = nlmpcmoveCodeGeneration(coreData, x, u, onlineData);
            
                % Apply optimal inputs and simulate next timestep
                x = UnicycleDiscrete(x,u,Ts);
            
                % Save States and Input Histories
                xHistory = [xHistory x];
                uHistory = [uHistory u];
                app.progressbar.Value = k/length(t);
            end
            
            MPCDataPkg = zeros(size(uHistory));

            for i = 1:1:size(uHistory,2)

                inputs = [uHistory(2,i), uHistory(1,i)];

                % Convert rad/s to pulses per 10 ms
                wheelPPS = floor((1120 * (inputs/(2*pi())))/100); 
                % We have 1120 pulses per revolution
    
                % Figure out how to transfer data through serial port
                MPCDataPkg(:,i) = wheelPPS;
            end
            
            empty = [0;0];
            MPCDataPkg = [MPCDataPkg,empty];

            app.MPCInputs = MPCDataPkg;           
            

            disp("Average MPC Iteration Time (ms): ");
            disp(toc/k * 1000);
            xHistory(:,1) = [];
            uHistory(:,1) = [];
            % Update State and Input plots
            set(app.omega_L_Planned,'XData',t,'YData',uHistory(1,:));
            set(app.omega_R_Planned,'XData',t,'YData',uHistory(2,:));
            app.ControlPlot.XLim = [0 t(end)];
            %legend(app.ControlPlot,["Planned \omega_{L} [rad/s]","Planned \omega_{R} [rad/s]"],'Location','southeast');

            set(app.xPlanned,'XData',t,'YData',xHistory(1,:));
            set(app.yPlanned,'XData',t,'YData',xHistory(2,:));
            set(app.tPlanned,'XData',t,'YData',xHistory(3,:));
            app.StatePlot.XLim = [0 t(end)];
            %legend(app.StatePlot,["Planned X","Planned Y","Planned \theta"],'Location','southeast');

            set(app.MobileRobotGUIUIFigure, 'currentaxes', app.RobotEnvironmentPlot);


            hold(app.RobotEnvironmentPlot,"on");
            arrowLength = 0.75;

            try
                disp(xHistory);
            catch
            end
            plot(app.RobotEnvironmentPlot,xHistory(1,:),xHistory(2,:),'b--','LineWidth',2, 'DisplayName','Planned Trajectory');
            %plot(app.RobotEnvironmentPlot,xHistory(1,1),xHistory(2,1),'r.',"MarkerSize",30);
            [startX, startY] = GenerateRobot(xHistory(1,1), xHistory(2,1), app.robotLength, app.robotWidth, xHistory(3,1));
            [endX, endY] = GenerateRobot(xHistory(1,end), xHistory(2,end), app.robotLength, app.robotWidth, xHistory(3,end));
            
            arrowXStart = xHistory(1,1)+arrowLength*cos(xHistory(3,1));          % X coordinate of arrow end
            arrowYStart = xHistory(2,1)+arrowLength*sin(xHistory(3,1));
            fill(app.RobotEnvironmentPlot, startX, startY,'r','DisplayName', 'Start Pose')
            plot(app.RobotEnvironmentPlot, [xHistory(1,1), arrowXStart], [xHistory(2,1), arrowYStart], 'Color', 'r', 'LineWidth',3,'DisplayName', 'Start Orientation');
             
            arrowXEnd = xHistory(1,end)+arrowLength*cos(xHistory(3,end));
            arrowYEnd = xHistory(2,end)+arrowLength*sin(xHistory(3,end));
            %plot(app.RobotEnvironmentPlot,xHistory(1,end),xHistory(2,end),'g.',"MarkerSize",30);
            fill(app.RobotEnvironmentPlot, endX, endY, 'g', 'DisplayName', 'Goal Pose')
            plot(app.RobotEnvironmentPlot, [xHistory(1,end), arrowXEnd], [xHistory(2,end), arrowYEnd], 'Color', 'g', 'LineWidth',3,'DisplayName','End Orientation');


            app.RobotEnvironmentPlot.XGrid = "on";
            app.RobotEnvironmentPlot.YGrid = "on";
            app.RobotEnvironmentPlot.GridColor = 'k';
            app.RobotEnvironmentPlot.GridAlpha = 0.85;
            app.RobotEnvironmentPlot.Title.String = "Robot Environment";
            %legend(app.RobotEnvironmentPlot);
            
        end
             
        function UpdateOccupancyGrid(app)
            pose = [app.xActual app.yActual app.thetaActual]; % in m and radians
            angle_range_1 = linspace((pi()/6)-(pi()/18),(pi()/6)+(pi()/18),10);
            angle_range_2 = linspace(-(pi()/18),(pi()/18),10);
            angle_range_3 = linspace(-(pi()/6)-(pi()/18),-(pi()/6)+(pi()/18),10);
            readings_1 = ones(1,10)*app.ultrasonic1*0.01;
            readings_2 = ones(1,10)*app.ultrasonic2*0.01;
            readings_3 = ones(1,10)*app.ultrasonic3*0.01;
            angles = [angle_range_1,angle_range_2,angle_range_3];
            readings = [readings_1,readings_2,readings_3]; % in m
            maxrange = 200;
             
            scan = removeInvalidData(lidarScan(readings,angles),'RangeLimits',[0.01 0.75]); % creates lidar scan object
            insertRay(app.occupancyGrid,pose,scan,maxrange); % updates occupancy grid
           
            set(app.MobileRobotGUIUIFigure, 'currentaxes', app.RobotEnvironmentPlot);
            show(app.occupancyGrid,'Parent',app.RobotEnvironmentPlot);
            

        end
        
        function plotRobot(app,axes)
             hold(axes,"on");
             [xfill,yfill] = GenerateRobot(app.xActual, app.yActual, app.robotLength, app.robotWidth, app.thetaActual);
             fill(axes,xfill,yfill,'b');
             plot(axes, [app.xActual app.xActual + app.robotLength*cos(app.thetaActual)],[app.yActual app.yActual + app.robotLength*sin(app.thetaActual)],"Color",'k','LineWidth',2);
        end
        
        function initPlotHandles(app)
            % Setup Axes
            app.StatePlot.Interactions = [];
            app.StatePlot.PickableParts = 'none';
            app.StatePlot.XLimitMethod = 'tight';
            hold(app.StatePlot,'on');

            app.ControlPlot.Interactions = [];
            app.ControlPlot.PickableParts = "none";
            app.ControlPlot.XLimitMethod = "tight";
            hold(app.ControlPlot,'on');

            app.RobotEnvironmentPlot.Interactions = [];
            app.RobotEnvironmentPlot.PickableParts = 'none';
            app.RobotEnvironmentPlot.XLimitMethod = "tight";
            hold(app.RobotEnvironmentPlot,"on");
            app.RobotEnvironmentPlot.NextPlot = "add";

            % Setup Signal Handles
            app.omega_L_PlotData = plot(app.ControlPlot, 0, 0,'.', 'MarkerSize',12,'Color',[0 0.4470 0.7410],'DisplayName','Actual \omega_{L} [rad/s]');
            app.omega_R_PlotData = plot(app.ControlPlot, 0, 0,'.', 'MarkerSize',12,'Color',[0.6350 0.0780 0.1840],'DisplayName','Actual \omega_{R} [rad/s]');
            app.omega_L_Planned = stairs(app.ControlPlot, 0, 0, 'Color',[0 0.4470 0.7410]);
            app.omega_R_Planned = stairs(app.ControlPlot, 0, 0, 'Color',[0.6350 0.0780 0.1840]);

            app.xPlotData = plot(app.StatePlot,0, 0,'.', 'MarkerSize',12,'Color',[0 0.4470 0.7410],'DisplayName','Actual X [m]');
            app.yPlotData = plot(app.StatePlot,0, 0,'.', 'MarkerSize',12,'Color',[0.6350 0.0780 0.1840],'DisplayName','Actual Y [m]');
            app.tPlotData = plot(app.StatePlot,0, 0,'.', 'MarkerSize',12,'Color',[0.9290 0.6940 0.1250],'DisplayName','Actual Theta [rad]');
            app.xPlanned = plot(app.StatePlot,0, 0,'Color',[0 0.4470 0.7410]);
            app.yPlanned = plot(app.StatePlot,0, 0,'Color',[0.6350 0.0780 0.1840]);
            app.tPlanned = plot(app.StatePlot,0, 0,'Color',[0.9290 0.6940 0.1250]);

            app.startPoseFill = fill(app.RobotEnvironmentPlot,[0 0 0 0],[0 0 0 0],'r');
            app.startPoseLine = plot(app.RobotEnvironmentPlot, 0, 0, 'Color', 'r');
            app.goalPoseFill = fill(app.RobotEnvironmentPlot,[0 0 0 0],[0 0 0 0],'g');
            app.goalPoseLine = plot(app.RobotEnvironmentPlot, 0, 0, 'Color', 'g');
            app.currentPoseFill = fill(app.RobotEnvironmentPlot,[0 0 0 0],[0 0 0 0],'b');
            app.currentPoseLine = plot(app.RobotEnvironmentPlot, 0, 0, 'Color', 'k');
            app.robotTrajectoryPlot = plot(app.RobotEnvironmentPlot, 0, 0, 'Color', 'b', 'LineStyle','--', 'LineWidth',2);

        end
        
        function clearPlots(app)
            set(app.xPlotData,'XData',0,'YData',0);
            set(app.yPlotData,'XData',0,'YData',0);
            set(app.tPlotData,'XData',0,'YData',0);
            set(app.omega_L_PlotData,'XData',0,'YData',0);
            set(app.omega_R_PlotData,'XData',0,'YData',0);
            set(app.xPlanned,'XData',0,'YData',0);
            set(app.yPlanned,'XData',0,'YData',0);
            set(app.tPlanned,'XData',0,'YData',0);
            set(app.omega_L_Planned,'XData',0,'YData',0);
            set(app.omega_R_Planned,'XData',0,'YData',0);
        end
        
        function setRobotGraphics(app,x,y,theta,fillHandle,headingHandle)
            % Compute Robot fill vertices
            [xdata, ydata] = GenerateRobot(x,y,app.robotLength,app.robotWidth,theta);
            
            % Set fill x and y data
            set(fillHandle,'XData',xdata,'YData',ydata);

            % Set heading plot x and y data
            set(headingHandle, 'XData', [x x + app.robotLength*cos(theta)], 'YData', [y y + app.robotLength*sin(theta)]);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RobotEnvironmentPlot.XLim = [0 app.OccupancyGridWidth];
            app.RobotEnvironmentPlot.YLim = [0 app.OccupancyGridWidth];
            
            initPlotHandles(app);
            readSerialCB(app);
            writeAutomatedDataCB(app);
            plotUpdateCB(app);
            SearchforCOMPortsButtonPushed(app);

            [app.soundData, app.soundRate] = audioread('whale.mp3');
            app.soundData = app.soundData(1:app.soundRate*10,:);
        end

        % Close request function: MobileRobotGUIUIFigure
        function MobileRobotGUIUIFigureCloseRequest(app, event)
            try
                stop(timerfindall)
                delete(timerfindall)  
                delete(app)
            catch
                delete(app)
            end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            try
                msgbox("Shutting Down GUI...");
                pause(1);
                MobileRobotGUIUIFigureCloseRequest(app);
            catch
            end
        end

        % Button pushed function: SearchforCOMPortsButton
        function SearchforCOMPortsButtonPushed(app, event)
            com = serialportlist();
            app.comPortList = com;
            value = app.COMPortsListBox.Value;
            app.COMPortsListBox.Items = com;
        end

        % Clicked callback: COMPortsListBox
        function COMPortsListBoxClicked(app, event)
            item = event.InteractionInformation.Item;
            app.selectedCOMPortIndex = item;
            app.ConnectLamp.Color = "y";
            app.ConnecttoCOMPortButton.Enable = "on";
        end

        % Button pushed function: ConnecttoCOMPortButton
        function ConnecttoCOMPortButtonPushed(app, event)
            target = app.comPortList(app.selectedCOMPortIndex);
            app.selectedCOMPort = target;
            serialObj = serialport(target,115200);
            app.SerialObject = serialObj;
            app.ConnectLamp.Color = 'g';
            app.DisconnectfromCOMPortButton.Enable = "on";
            app.ManualControlSwitch.Enable = "on";
            app.ActivateAutonomousControlButton.Enable = "on";
            start(app.readSerialTimerObject);
        end

        % Button pushed function: DisconnectfromCOMPortButton
        function DisconnectfromCOMPortButtonPushed(app, event)
            stop(app.readSerialTimerObject);
            delete(app.SerialObject);
            app.ConnectLamp.Color = 'r';
            app.ManualControlSwitch.Value = "Off";
            app.ActivateAutonomousControlButton.Enable = "off";
            
            % Search for COM Ports again and allows you to restart the
            % choosing process
            app.ConnecttoCOMPortButton.Enable = "off";
            SearchforCOMPortsButtonPushed(app);

        end

        % Value changed function: ManualControlSwitch
        function ManualControlSwitchValueChanged(app, event)
            value = app.ManualControlSwitch.Value;
            if value == "On"
                % Clear all plots
                clearPlots(app);
                app.plotTime = 0;
                
                % Plays whale sounds
                sound(app.soundData, app.soundRate);

                % Reset the xlimit
                app.StatePlot.XLim = [0 inf];
                app.ControlPlot.XLim = [0 inf];
    
                % Enable Reset Occupancy Grid Button
                app.ResetOccupancyGridButton.Enable = 'on';
    
                % Starts plot timer object
                start(app.plotDataTimerObject);
    
                % Turn on keyboard control
                app.KeyboardControlLamp.Color = 'g';
                app.ActivateAutonomousControlButton.Enable = "off";
                app.driveMode = 1;
            end

            if value == "Off"
                % Stops the plotData timer Object
                stop(app.plotDataTimerObject)
    
                % Disable reset occupancy grid button
                app.ResetOccupancyGridButton.Enable = 'off';
                
                % Turns off keyboard control by setting driveMode to 2, neither
                % autonomous or manual
                app.driveMode = 2;
                app.KeyboardControlLamp.Color = 'r';
    
                % Sets wheel gauges to 0
                app.LeftWheelOmegaGauge.Value = 0;
                app.RightWheelOmegaGauge.Value = 0;
    
                % Resets the values of both wheels to 0 
                app.omegaL = 0;
                app.omegaR = 0;
    
                % Stops the robot
                write(app.SerialObject, [0, 0], 'int8')
                app.ActivateAutonomousControlButton.Enable = "on";
    
                % Reset Data Package values to 0
                app.DataPkg = [0,0];
            end
            
        end

        % Key press function: MobileRobotGUIUIFigure
        function MobileRobotGUIUIFigureKeyPress(app, event)
            key = event.Key;
            if app.driveMode == 1
                if key == "uparrow"
                    % Increase RPM by 5 for both wheels
                    app.omegaL = app.omegaL + 5;
                    app.omegaR = app.omegaR + 5;
                    if app.omegaL < -200
                        app.omegaL = -200;
                    elseif app.omegaL > 200
                        app.omegaL = 200;
                    else
                    end

                    if app.omegaR < -200
                        app.omegaR = -200;
                    elseif app.omegaR > 200
                        app.omegaR = 200;
                    else
                    end
                    
                    % Convert from RPM to rad/s
                    app.LeftWheelOmegaGauge.Value = app.omegaL * 0.10472;
                    app.RightWheelOmegaGauge.Value = app.omegaR * 0.10472;
                elseif key == "downarrow"
                    % Decrease RPM by 5 for both wheels
                    app.omegaL = app.omegaL - 5;
                    app.omegaR = app.omegaR - 5;
                    if app.omegaL < -200
                        app.omegaL = -200;
                    elseif app.omegaL > 200
                        app.omegaL = 200;
                    else
                    end

                    if app.omegaR < -200
                        app.omegaR = -200;
                    elseif app.omegaR > 200
                        app.omegaR = 200;
                    else
                    end

                    % Convert from RPM to rad/s
                    app.LeftWheelOmegaGauge.Value = app.omegaL * 0.10472;
                    app.RightWheelOmegaGauge.Value = app.omegaR * 0.10472;
                elseif key == "rightarrow"
                    % Increase RPM by 2.5 for left wheel and decrease RPM
                    % by 2.5 for right wheel
                    app.omegaL = app.omegaL - 5;
                    app.omegaR = app.omegaR + 5;
                    if app.omegaL < -200
                        app.omegaL = -200;
                    elseif app.omegaL > 200
                        app.omegaL = 200;
                    else
                    end

                    if app.omegaR < -200
                        app.omegaR = -200;
                    elseif app.omegaR > 200
                        app.omegaR = 200;
                    else
                    end
                    app.LeftWheelOmegaGauge.Value = app.omegaL * 0.10472;
                    app.RightWheelOmegaGauge.Value = app.omegaR * 0.10472;
                elseif key == "leftarrow"
                    % Increase RPM by 2.5 for right wheel and decrease RPM
                    % by 2.5 for left wheel
                    app.omegaL = app.omegaL + 5;
                    app.omegaR = app.omegaR - 5;
                    if app.omegaL < -200
                        app.omegaL = -200;
                    elseif app.omegaL > 200
                        app.omegaL = 200;
                    else
                    end

                    if app.omegaR < -200
                        app.omegaR = -200;
                    elseif app.omegaR > 200
                        app.omegaR = 200;
                    else
                    end

                    % Convert from RPM to rad/s
                    app.LeftWheelOmegaGauge.Value = app.omegaL * 0.10472;
                    app.RightWheelOmegaGauge.Value = app.omegaR * 0.10472;
                end

                rightWheelRPM = app.omegaR;
                leftWheelRPM = app.omegaL;
                % Convert RPM to pulses per 10 millisecond
                % We have 1120 pulses per revolution

                rightWheelPPS= floor((1120*(rightWheelRPM/60))/100);
                leftWheelPPS = floor((1120*(leftWheelRPM/60))/100);

                % Stores manual data into a package that will be written
                app.DataPkg = [leftWheelPPS, rightWheelPPS];
                write(app.SerialObject, app.DataPkg, "int8");
            end
        end

        % Button pushed function: ActivateAutonomousControlButton
        function ActivateAutonomousControlButtonPushed(app, event)
            % Disables manual control
            app.ManualControlSwitch.Value = "Off";
            app.ManualControlSwitch.Enable = "off";
            app.DisableAutonomousControlButton.Enable = "on";

            % Enables path planning
            app.PlanTrajectoryButton.Enable = "on";

            % Enables Reset Occupancy Grid
            app.ResetOccupancyGridButton.Enable = "on";
            % Enalbe Load Occupancy Grid
            app.LoadOccupancyGridButton.Enable = 'on';

            % Sets driveMode to 0 as to prevent keyboard control
            app.driveMode = 0;


        end

        % Button pushed function: PlanTrajectoryButton
        function PlanTrajectoryButtonPushed(app, event)
            if app.driveMode == 0
                
                % Clear all plots
                clearPlots(app);

                app.plotTime = 0;

                % Replots the occupancy grid
                show(app.occupancyGrid,'Parent',app.RobotEnvironmentPlot);
                
                % Code will display the occupancy grid and prompt the user
                % to click an end point that it wants user to move to
                selectMode = 1; % Set this to 1 to add pop-up for picking destination
                if(selectMode == 1)
                    show(app.occupancyGrid)
                    plotRobot(app,gca);
                    xlabel('Please select the target using the left mouse button', 'Color', 'black');
                    but = 0;
                    while (but ~= 1) %Repeat until the Left button is not clicked
                        [xval,yval,but]=ginput(1);
                    end
                    close all
                    xval = round(xval, 2);
                    yval = round(yval, 2);
                    
                    % Sets the goal poses to the value clicked on by the user
                    app.GoalPoseX.Value = xval;
                    app.GoalPoseY.Value = yval;
                    app.GoalPoseTheta.Value = atan2(yval - app.yActual,xval - app.xActual);
                end
                try
                    app.progressbar= uiprogressdlg(app.MobileRobotGUIUIFigure, 'Title','Please Wait ... ','Message','Planning Trajectory ... ');
                    % Executes path planning and MPC controller
                    app.progressbar.Message = "Planning Path . . .";
                    pathPlanning(app);
                    app.progressbar.Message = "Computing Optimal Control Inputs . . .";
                    MPCPlanner(app);
                    close(app.progressbar);

                    % Sets the max number of times the timer object will
                    % execute
                    app.writeAutomatedTimerObject.TaskstoExecute = size(app.MPCInputs,2);
    
                    % Enables the ability to execute trajectory
                    app.TrajectoryStatusLamp.Color = 'g';
                    app.ExecuteTrajectoryButton.Enable = "on";
                catch
                    msgbox("Error - Failed to Plan Path","Path Planning Exception","error");
                end
            end
        end

        % Button pushed function: ExecuteTrajectoryButton
        function ExecuteTrajectoryButtonPushed(app, event)
            
            % Disable planning trajectory
            app.PlanTrajectoryButton.Enable = "off";

            % Plays whale sounds
            sound(app.soundData, app.soundRate);

            % Start the plot data timer object
            try
                start(app.plotDataTimerObject);
            catch
            end

            % Play

            % Index into the automated Data Package and send this to be
            % written
            start(app.writeAutomatedTimerObject);

        end

        % Button pushed function: DisableAutonomousControlButton
        function DisableAutonomousControlButtonPushed(app, event)
            app.driveMode = 2;

            % Disable Reset & Load Occupancy Grid
            app.LoadOccupancyGridButton.Enable = 'off';
            app.ResetOccupancyGridButton.Enable = 'off';

            % Disables plan trajectory and execute trajectory button
            app.PlanTrajectoryButton.Enable = "off";
            app.ExecuteTrajectoryButton.Enable = "off";
            app.TrajectoryStatusLamp.Color = 'r';
            
            % Enable Activate Manual Control Button
            app.ManualControlSwitch.Enable = "on";

            % Stops the plotData timer Object
            stop(app.plotDataTimerObject)

            % Sets motor wheel speeds to 0
            write(app.SerialObject, [0,0], "int8");
        end

        % Button pushed function: ResetOccupancyGridButton
        function ResetOccupancyGridButtonPushed(app, event)
            cla(app.RobotEnvironmentPlot);
            app.occupancyGrid.setOccupancy(0);
            show(app.occupancyGrid,'Parent',app.RobotEnvironmentPlot);
        end

        % Button pushed function: LoadOccupancyGridButton
        function LoadOccupancyGridButtonPushed(app, event)
            
            [file,location] = uigetfile('..\Path Planning\Grids',...
                        'Select an Grid File');
            if isequal(file,0)
                disp('User Selected Cancel')
            else
                disp(['User selected ', fullfile(location,file)])
            end
            
            load([location file],'map');

            app.occupancyGrid = map;
            cla(app.RobotEnvironmentPlot);
            show(app.occupancyGrid,'Parent',app.RobotEnvironmentPlot);
        end

        % Size changed function: MobileRobotGUIUIFigure
        function updateAppLayout(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MobileRobotGUIUIFigure = uifigure('Visible', 'off');
            app.MobileRobotGUIUIFigure.Name = 'Mobile Robot GUI';
            app.MobileRobotGUIUIFigure.CloseRequestFcn = createCallbackFcn(app, @MobileRobotGUIUIFigureCloseRequest, true);
            app.MobileRobotGUIUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.MobileRobotGUIUIFigure.KeyPressFcn = createCallbackFcn(app, @MobileRobotGUIUIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.MobileRobotGUIUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.RobotEnvironmentPlot = uiaxes(app.GridLayout2);
            title(app.RobotEnvironmentPlot, 'Robot Environment')
            xlabel(app.RobotEnvironmentPlot, 'X [m]')
            ylabel(app.RobotEnvironmentPlot, 'Y [m]')
            zlabel(app.RobotEnvironmentPlot, 'Z')
            app.LoadOccupancyGridButton = uibutton(app.GridLayout2, 'push');
            app.LoadOccupancyGridButton.ButtonPushedFcn = createCallbackFcn(app, @LoadOccupancyGridButtonPushed, true);
            app.LoadOccupancyGridButton.Enable = 'off';
            app.LoadOccupancyGridButton.Text = 'Load Occupancy Grid';
            app.ResetOccupancyGridButton = uibutton(app.GridLayout2, 'push');
            app.ResetOccupancyGridButton.ButtonPushedFcn = createCallbackFcn(app, @ResetOccupancyGridButtonPushed, true);
            app.ResetOccupancyGridButton.Enable = 'off';
            app.ResetOccupancyGridButton.Text = 'Reset Occupancy Grid';
            app.STOPButton = uibutton(app.GridLayout2, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.Image4 = uiimage(app.GridLayout2);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'Trajectory Plot Legend.png');
            app.RightPanel = uipanel(app.GridLayout);
            app.StatePlot = uiaxes(app.RightPanel);
            title(app.StatePlot, 'State Variables')
            xlabel(app.StatePlot, 'Time [s]')
            ylabel(app.StatePlot, '[m], [rad]')
            zlabel(app.StatePlot, 'Z')
            app.ControlPlot = uiaxes(app.RightPanel);
            title(app.ControlPlot, 'Control Inputs')
            xlabel(app.ControlPlot, 'Time [s]')
            ylabel(app.ControlPlot, '\omega [rad/s]')
            zlabel(app.ControlPlot, 'Z')
            app.TabGroup = uitabgroup(app.RightPanel);
            app.SerialPortTab = uitab(app.TabGroup);
            app.SerialPortTab.Title = 'Serial Port';
            app.SearchforCOMPortsButton = uibutton(app.SerialPortTab, 'push');
            app.SearchforCOMPortsButton.ButtonPushedFcn = createCallbackFcn(app, @SearchforCOMPortsButtonPushed, true);
            app.SearchforCOMPortsButton.Text = 'Search for COM Ports';
            app.ConnecttoCOMPortButton = uibutton(app.SerialPortTab, 'push');
            app.ConnecttoCOMPortButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoCOMPortButtonPushed, true);
            app.ConnecttoCOMPortButton.Enable = 'off';
            app.ConnecttoCOMPortButton.Text = 'Connect to COM Port';
            app.TextArea = uitextarea(app.SerialPortTab);
            app.TextArea.Value = {'1. Click on "Search for COM Ports" button once your ESP32 is plugged in'; '2. Select the correct COM Channel your ESP32 is connect'; '3. Click "Connect to COM Port". Light will flash green once connected'; '4. Click "Disconnect from COM Port" to disconnect.'};
            app.COMPortsListBoxLabel = uilabel(app.SerialPortTab);
            app.COMPortsListBoxLabel.Text = {'COM'; 'Ports'};
            app.COMPortsListBox = uilistbox(app.SerialPortTab);
            app.COMPortsListBox.ClickedFcn = createCallbackFcn(app, @COMPortsListBoxClicked, true);
            app.ConnectLamp = uilamp(app.SerialPortTab);
            app.DisconnectfromCOMPortButton = uibutton(app.SerialPortTab, 'push');
            app.DisconnectfromCOMPortButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectfromCOMPortButtonPushed, true);
            app.DisconnectfromCOMPortButton.Enable = 'off';
            app.DisconnectfromCOMPortButton.Text = 'Disconnect from COM Port';
            app.ManualModeTab = uitab(app.TabGroup);
            app.ManualModeTab.Title = 'Manual Mode';
            app.KeyboardControlLampLabel = uilabel(app.ManualModeTab);
            app.KeyboardControlLampLabel.Text = 'Keyboard Control';
            app.KeyboardControlLamp = uilamp(app.ManualModeTab);
            app.LeftWheelradsLabel = uilabel(app.ManualModeTab);
            app.LeftWheelradsLabel.Text = {'Left Wheel'; '(rad/s)'};
            app.LeftWheelOmegaGauge = uigauge(app.ManualModeTab, 'circular');
            app.LeftWheelOmegaGauge.Limits = [-20 20];
            app.LeftWheelOmegaGauge.MajorTicks = [-20 -15 -10 -5 0 5 10 15 20];
            app.LeftWheelOmegaGauge.MinorTicks = [-20 -19 -18 -17 -16 -15 -14 -13 -12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.RightWheelradsLabel = uilabel(app.ManualModeTab);
            app.RightWheelradsLabel.Text = {'Right Wheel'; '(rad/s)'};
            app.RightWheelOmegaGauge = uigauge(app.ManualModeTab, 'circular');
            app.RightWheelOmegaGauge.Limits = [-20 20];
            app.RightWheelOmegaGauge.MajorTicks = [-20 -15 -10 -5 0 5 10 15 20];
            app.RightWheelOmegaGauge.MinorTicks = [-20 -19 -18 -17 -16 -15 -14 -13 -12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.ManualControlSwitchLabel = uilabel(app.ManualModeTab);
            app.ManualControlSwitchLabel.Text = 'Manual Control';
            app.ManualControlSwitch = uiswitch(app.ManualModeTab, 'slider');
            app.ManualControlSwitch.ValueChangedFcn = createCallbackFcn(app, @ManualControlSwitchValueChanged, true);
            app.ManualControlSwitch.Enable = 'off';
            app.AutonomousModeTab = uitab(app.TabGroup);
            app.AutonomousModeTab.Title = 'Autonomous Mode';
            app.PlanTrajectoryButton = uibutton(app.AutonomousModeTab, 'push');
            app.PlanTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @PlanTrajectoryButtonPushed, true);
            app.PlanTrajectoryButton.Enable = 'off';
            app.PlanTrajectoryButton.Text = 'Plan Trajectory';
            app.ExecuteTrajectoryButton = uibutton(app.AutonomousModeTab, 'push');
            app.ExecuteTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteTrajectoryButtonPushed, true);
            app.ExecuteTrajectoryButton.Enable = 'off';
            app.ExecuteTrajectoryButton.Text = 'Execute Trajectory';
            app.XEditFieldLabel = uilabel(app.AutonomousModeTab);
            app.XEditFieldLabel.Text = 'X';
            app.GoalPoseX = uieditfield(app.AutonomousModeTab, 'numeric');
            app.YEditFieldLabel = uilabel(app.AutonomousModeTab);
            app.YEditFieldLabel.Text = 'Y';
            app.GoalPoseY = uieditfield(app.AutonomousModeTab, 'numeric');
            app.ThetaLabel = uilabel(app.AutonomousModeTab);
            app.ThetaLabel.Text = 'Theta';
            app.GoalPoseTheta = uieditfield(app.AutonomousModeTab, 'numeric');
            app.GoalPoseLabel = uilabel(app.AutonomousModeTab);
            app.GoalPoseLabel.Text = 'Goal Pose';
            app.TrajectoryStatusLampLabel = uilabel(app.AutonomousModeTab);
            app.TrajectoryStatusLampLabel.Text = 'Trajectory Status';
            app.TrajectoryStatusLamp = uilamp(app.AutonomousModeTab);
            app.CurrentPoseLabel = uilabel(app.AutonomousModeTab);
            app.CurrentPoseLabel.Text = 'Current Pose';
            app.thetaLabel = uilabel(app.AutonomousModeTab);
            app.thetaLabel.Text = 'Theta';
            app.CurrPoseTheta = uieditfield(app.AutonomousModeTab, 'numeric');
            app.CurrPoseTheta.Enable = 'off';
            app.YEditField_2Label = uilabel(app.AutonomousModeTab);
            app.YEditField_2Label.Text = 'Y';
            app.CurrPoseY = uieditfield(app.AutonomousModeTab, 'numeric');
            app.CurrPoseY.Enable = 'off';
            app.XEditField_2Label = uilabel(app.AutonomousModeTab);
            app.XEditField_2Label.Text = 'X';
            app.CurrPoseX = uieditfield(app.AutonomousModeTab, 'numeric');
            app.CurrPoseX.Enable = 'off';
            app.ActivateAutonomousControlButton = uibutton(app.AutonomousModeTab, 'push');
            app.ActivateAutonomousControlButton.ButtonPushedFcn = createCallbackFcn(app, @ActivateAutonomousControlButtonPushed, true);
            app.ActivateAutonomousControlButton.Enable = 'off';
            app.ActivateAutonomousControlButton.Text = {'Activate Autonomous '; 'Control'};
            app.DisableAutonomousControlButton = uibutton(app.AutonomousModeTab, 'push');
            app.DisableAutonomousControlButton.ButtonPushedFcn = createCallbackFcn(app, @DisableAutonomousControlButtonPushed, true);
            app.DisableAutonomousControlButton.Enable = 'off';
            app.DisableAutonomousControlButton.Text = {'Disable Autonomous'; 'Control'};
            app.DebugTab = uitab(app.TabGroup);
            app.DebugTab.Title = 'Debug';
            app.BytesAvailableEditFieldLabel = uilabel(app.DebugTab);
            app.BytesAvailableEditFieldLabel.Text = 'Bytes Available';
            app.BytesAvailableEditField = uieditfield(app.DebugTab, 'numeric');
            app.BytesAvailableEditField.Editable = 'off';
            app.DebugPoseLabel = uilabel(app.DebugTab);
            app.DebugPoseLabel.Text = 'Current Pose';
            app.XEditField_2Label_2 = uilabel(app.DebugTab);
            app.XEditField_2Label_2.Text = 'X';
            app.DebugPoseX = uieditfield(app.DebugTab, 'numeric');
            app.DebugPoseX.Editable = 'off';
            app.YEditField_2Label_2 = uilabel(app.DebugTab);
            app.YEditField_2Label_2.Text = 'Y';
            app.DebugPoseY = uieditfield(app.DebugTab, 'numeric');
            app.DebugPoseY.Editable = 'off';
            app.thetaLabel_2 = uilabel(app.DebugTab);
            app.thetaLabel_2.Text = 'Theta';
            app.DebugPoseTheta = uieditfield(app.DebugTab, 'numeric');
            app.DebugPoseTheta.Editable = 'off';
            app.DebugMotorLabel = uilabel(app.DebugTab);
            app.DebugMotorLabel.Text = 'Motor Readings';
            app.DebugUTLabel = uilabel(app.DebugTab);
            app.DebugUTLabel.Text = {'Ultrasonic'; 'Readings'};
            app.XEditField_2Label_3 = uilabel(app.DebugTab);
            app.XEditField_2Label_3.Text = {'Left Motor'; '[rad/s]'};
            app.DebugLeftMotor = uieditfield(app.DebugTab, 'numeric');
            app.DebugLeftMotor.Editable = 'off';
            app.XEditField_2Label_4 = uilabel(app.DebugTab);
            app.XEditField_2Label_4.Text = {'Right Motor'; '[rad/s]'};
            app.DebugRightMotor = uieditfield(app.DebugTab, 'numeric');
            app.DebugRightMotor.Editable = 'off';
            app.XEditField_2Label_5 = uilabel(app.DebugTab);
            app.XEditField_2Label_5.Text = 'Sensor 1';
            app.DebugUT1 = uieditfield(app.DebugTab, 'numeric');
            app.DebugUT1.Editable = 'off';
            app.XEditField_2Label_6 = uilabel(app.DebugTab);
            app.XEditField_2Label_6.Text = 'Sensor 2';
            app.DebugUT2 = uieditfield(app.DebugTab, 'numeric');
            app.DebugUT2.Editable = 'off';
            app.XEditField_2Label_7 = uilabel(app.DebugTab);
            app.XEditField_2Label_7.Text = 'Sensor 3';
            app.DebugUT3 = uieditfield(app.DebugTab, 'numeric');
            app.DebugUT3.Editable = 'off';
            app.Image5 = uiimage(app.RightPanel);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'Control Plot Legend.png');
            app.Image6 = uiimage(app.RightPanel);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'State Plot Legend.png');
            % Show the figure after all components are created
            app.MobileRobotGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Version5_MobileRobotControlGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MobileRobotGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MobileRobotGUIUIFigure)
        end
    end
end