classdef MobileRobotControlGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        CSVLocationEditField          matlab.ui.control.EditField
        CSVLocationEditFieldLabel     matlab.ui.control.Label
        STOPButton                    matlab.ui.control.Button
        SetDataloggingLocationButton  matlab.ui.control.Button
        ResetOccupancyGridButton      matlab.ui.control.Button
        ResetOdometryButton           matlab.ui.control.Button
        RobotEnvironmentPlot          matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        TabGroup                      matlab.ui.container.TabGroup
        SerialPortTab                 matlab.ui.container.Tab
        DisconnectfromCOMPortButton   matlab.ui.control.Button
        ConnectLamp                   matlab.ui.control.Lamp
        COMPortsListBox               matlab.ui.control.ListBox
        COMPortsListBoxLabel          matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        ConnecttoCOMPortButton        matlab.ui.control.Button
        SearchforCOMPortsButton       matlab.ui.control.Button
        AutonomousControlTab          matlab.ui.container.Tab
        ActivateAutonomousControlButton  matlab.ui.control.Button
        CurrPoseX                     matlab.ui.control.NumericEditField
        XEditField_2Label             matlab.ui.control.Label
        CurrPoseY                     matlab.ui.control.NumericEditField
        YEditField_2Label             matlab.ui.control.Label
        CurrPoseTheta                 matlab.ui.control.NumericEditField
        thetaLabel                    matlab.ui.control.Label
        CurrentPoseLabel              matlab.ui.control.Label
        TrajectoryStatusLamp          matlab.ui.control.Lamp
        TrajectoryStatusLampLabel     matlab.ui.control.Label
        GoalPoseLabel                 matlab.ui.control.Label
        GoalPoseTheta                 matlab.ui.control.NumericEditField
        ThetaLabel                    matlab.ui.control.Label
        GoalPoseY                     matlab.ui.control.NumericEditField
        YEditFieldLabel               matlab.ui.control.Label
        GoalPoseX                     matlab.ui.control.NumericEditField
        XEditFieldLabel               matlab.ui.control.Label
        ExecuteTrajectoryButton       matlab.ui.control.Button
        PlanTrajectoryButton          matlab.ui.control.Button
        ManualControlTab              matlab.ui.container.Tab
        RightWheelRPMGauge            matlab.ui.control.Gauge
        RightWheelRPMGaugeLabel       matlab.ui.control.Label
        LeftWheelRPMGauge             matlab.ui.control.Gauge
        LeftWheelRPMGaugeLabel        matlab.ui.control.Label
        StopManualControlButton       matlab.ui.control.Button
        KeyboardControlLamp           matlab.ui.control.Lamp
        KeyboardControlLampLabel      matlab.ui.control.Label
        ActiviateManualControlButton  matlab.ui.control.Button
        ControlPlot                   matlab.ui.control.UIAxes
        StatePlot                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        robotLength = 0.2
        robotWidth = 0.2

        writeTimeObject % timer object for writing
        readTimeObject % timer object for 
        writeAutomateTimeObject % timer object for writing MPC controls
        controlTimeObject % timer object for updating control plot
        odometryCalcTimeObject % timer object for calculating odometry


        path % global variable for path generated
        occupancyGrid % global variable for occupancy grid
        omegaL = 0 % global variable for angular velocity of left wheel
        omegaR = 0 % global variable for angular velocity of right wheel
        optimalInputs % global array for OL control inputs from MPC
        executePath = 0 % 0 means don't execute, 1 means do execute

        comPortList
        selectedCOMPortIndex 
        selectedCOMPort % global variable for the selected COM Port
        SerialObject % serial object to be connected to

        robotData % data received from the robot read through serial port
        automatedDataPkg % array sent from MPC OL controller
        manualDataPkg % data sent from keyboard

        estimatedTravelTime % estimation of how long it will take robot to traverse path
        driveMode = 2 % drive mode for the robot. 0 for autonomous, 1 for manual

        rightWheelPulse % received pulse data from the mobile robot to do calculations with
        leftWheelPulse
        ultrasonic1
        ultrasonic2
        ultrasonic3
        count

        xActual % state estimation calc for x
        yActual % state estimation calc for y
        thetaActual= 0 % state estimation calc for theta
        timeActual % calculation for the current time
        OccupancyGridWidth = 5; % Robot environment occupancy grid width
    end
    
    methods (Access = private)

        function readSerialComTime(app)
            % Create a timer object that executes the computation every 0.1 second
            app.readTimeObject = timer('ExecutionMode', 'fixedRate', 'Period', 0.1, 'TimerFcn', @readSerial);

            % Create a timer object that executes the computation every 0.1 second
            %app.writeTimeObject = timer('ExecutionMode', 'fixedRate', 'Period', 0.1, 'TimerFcn', @writeSerial);
            
            % Start the timer
            %start(app.writeTimeObject);
            start(app.readTimeObject);
            
            function readSerial(~,~)
                app.robotData = read(app.SerialObject, 36, 'int8');
                app.rightWheelPulse = app.robotData(1);
                app.leftWheelPulse = app.robotData(2);
                app.ultrasonic1 = app.robotData(3);
                app.ultrasonic2 = app.robotData(4);
                app.ultrasonic3 = app.robotData(5);
                %app.count = app.robotData(6);
            end

            % function writeSerial(~, ~)
            %     % Checks which drive mode we are in to do required
            % 
            %     i = 1;
            %     % Fix autonomous 
            %     if app.driveMode == 0 && app.executePath == 1% autonomous     
            %         if i < size(app.automatedDataPkg,2)
            %             write(app.SerialObject, app.automatedDataPkg(:,i), "int8")
            %             i = i + 1;
            %         end
            % 
            %     elseif app.driveMode == 1 % manual
            % 
            %         %disp(manualDataPkg)
            %         write(app.SerialObject, app.manualDataPkg, "int8")
            %     else
            %     end
            % 
            % end
        end

        function writeAutomatedSerial(app)
            app.writeAutomateTimeObject = timer('ExecutionMode', 'fixedRate', 'Period', 0.1, 'TimerFcn', @writeAutomate);
            i = 1;   
            start(app.writeAutomateTimeObject);
            function writeAutomate(~,~)
                if i < size(app.automatedDataPkg,2)
                    write(app.SerialObject, app.automatedDataPkg(:,i), "int8")
                    i = i + 1;
                else
                    write(app.SerialObject, [0,0], "int8")
                    stop(app.writeAutomateTimeObject)
                end
            end

            
        end

        function writeManualSerial(app)
            write(app.SerialObject, app.manualDataPkg, "int8")
        end
        

        function createOccupancyGrid(app)
            try
                % Creates occupancy map with width and height in units of meters
                % resolution of 10 means 10 cells per meter
                map = binaryOccupancyMap(10,10,10);
                
                % Creating a numeric array with matching size
                walls = zeros(100,100);
                walls(1,:) = 1; % Top wall
                walls(end,:)= 1; % Bottom wall
                walls(:,1) = 1; % Left wall
                walls(:,end) = 1; % Right wall
                walls(1:50,15) = 1; % Left division
                walls(25:end, 40)= 1; % Middle division
                walls(1:30,60) = 1; %Right Division
                
                % Set occupancy of world locations and show map
                
                
                setOccupancy(map, [1,1], walls, "grid")
                
                %Inflate the thickness of our occupied spaces to give more room for robot
                %to navigate around obstacles
                inflate(map, 0.2)
                    
                app.occupancyGrid = map;

                set(app.UIFigure, 'currentaxes', app.RobotEnvironmentPlot);
                show(map,'Parent',app.RobotEnvironmentPlot);
            catch
                error("Failed to generate occupancy grid.");
            end
        end

        function pathPlanning(app)
            try
                % load occupancy grid
                map = app.occupancyGrid;
                
                % Create State Space
                ss = stateSpaceSE2;
                
                % Update state space bounds to be the same as map limits.
                ss.StateBounds = [map.XWorldLimits;map.YWorldLimits;[-pi pi]];
                
                % Create state validator object to check for collisions
                sv = validatorOccupancyMap(ss);
                
                % Assign the map to the state validator object
                sv.Map = map;
                
                % Plan and visualize path
                % Initialize the planner and specify values for Minimum turning radius and
                % motion primitive length. Getting rid of either of those drastically 
                % increases computation time
                planner = plannerHybridAStar(sv, ...
                                             MinTurningRadius=0.15, ...
                                             MotionPrimitiveLength=0.2);
                
                % Define start and goal poses for the vehicle as [x, y, theta] vectors. x 
                % and y specify the position in meters, and theta specifies the orientation
                % angle in radians.
                
                % Change these values later
                startPose = [2 1 pi/2]; % [meters, meters, radians]
    
                %goalPose = [8 8 2*pi];
                goalPose = [app.GoalPoseX.Value app.GoalPoseY.Value app.GoalPoseTheta.Value];
                
                %Plan a path from the start pose to the goal pose.

                refpath = plan(planner,startPose,goalPose,SearchMode='greedy');     
                
                % Extract the path poses from the path object
                p = refpath.States;
                p = p';
                % Find out how long the path length is and calculate the
                % amount of time it will take to traverse the length to
                % update the end condition of timePoints

                xValues = p(1,:);
                yValues = p(2,:);
                totalDistance = 0;
                for i = 1:1:length(xValues)-1
                    tempDistance = sqrt(((xValues(i+1)-xValues(i))^2)+((yValues(i+1)-yValues(i))^2));
                    totalDistance = totalDistance + tempDistance;
                end
                robotAvgSpeed = 0.70;
                endTimePoint = totalDistance/robotAvgSpeed;

                app.estimatedTravelTime = endTimePoint;
                timePoints = [0 endTimePoint];
                timeVector = 0:0.1:endTimePoint;

                [q, qd, qdd, pp] = bsplinepolytraj(p,timePoints,timeVector);
                
                app.path = q;
                %disp(app.path);
            catch
                error("Failed to path plan.");
            end
        end

        function MPCPlanner(app)
            % MPC Function setups
            
            function y = UnicycleOutput(x,u,params)
                y = x; % Full State Feedback
            end

            function z_dot = UnicycleContinuous(z,u)
                % Robot Constant Parameters
                r_wheel = 0.030; % Wheel Radius [m]
                d_wheel = 0.200; % Distance Between Wheels [m]
                
                % States
                x = z(1);       % Vehicle X Position    [m]
                y = z(2);       % Vehicle Y Position    [m]
                theta = z(3);   % Vehicle Heading       [rad]
                
                % Inputs
                w_l = u(1);     % Left Wheel Angular Velocity   [rad/s]
                w_r = u(2);     % Left Wheel Angular Velocity   [rad/s]
                
                % Continuous Dynamics
                z_dot = [   (r_wheel/2*w_r + r_wheel/2*w_l)*cos(theta);...
                            (r_wheel/2*w_r + r_wheel/2*w_l)*sin(theta);...
                            r_wheel*w_r/d_wheel - r_wheel*w_l/d_wheel];
            end

            function zk1 = UnicycleDiscrete(zk,uk,Ts)
                M = 10;
                delta = Ts/M;
                zk1 = zk;
                for ct = 1:M
                    zk1 = zk1 + delta*UnicycleContinuous(zk1,uk);
                end
            end

            % MPC Controller Creation and Setup
            % Set Number of States (nx), number of outputs (ny), and inputs or Manipulated Variables (nu)
            nx = 3;
            ny = 3; % Assuming full state feedback
            nu = 2;
            
            % Create a non-linear MPC controller
            MPCController = nlmpc(nx,ny,nu);
            
            % Set Discrete Sampling Time in seconds
            Ts = 0.1;
            MPCController.Ts = Ts;
            
            % Set Prediction Horizon and Control Horizon
            MPCController.PredictionHorizon = 10;
            MPCController.ControlHorizon = 5;
            
            % Set the discrete time system model for Unicycle Robot
            MPCController.Model.StateFcn = "UnicycleDiscrete";
            MPCController.Model.IsContinuousTime = false;
            MPCController.Model.NumberOfParameters = 1;
            
            % Set the system output model for the unicycle robot
            MPCController.Model.OutputFcn = "UnicycleOutput";
            
            % Provide the system Jacobian
            % MPCController.Jacobian.OutputFcn = @(x,u,Ts) [1 0 -u(1)*sin(x(3)); 0 1 u(1)*sin(x(3)); 0 0 1];
            
            % Define MPC Cost
            MPCController.Weights.OutputVariables = [10 10 0]; % State Error Cost
            MPCController.Weights.ManipulatedVariables = [0.1 0.1]; % Input Cost
            MPCController.Weights.ManipulatedVariablesRate = [0.1 0.1]; % Input Rate Cost
            
            % Define Constraint on State Variables
            MPCController.OV(1).Min = -10;
            MPCController.OV(1).Max = 10;
            
            MPCController.OV(2).Min = -10;
            MPCController.OV(2).Max = 10;
            
            % Define Constraints on Actuator Inputs
            MPCController.MV(1).Min = -20;
            MPCController.MV(1).Max = 40;
            
            MPCController.MV(2).Min = -20;
            MPCController.MV(2).Max = 40;
            
            % Define Constraints on Actuator Input Rate of Change
            MPCController.MV(1).RateMin = -5;
            MPCController.MV(1).RateMax = 5;
            
            MPCController.MV(2).RateMin = -5;
            MPCController.MV(2).RateMax = 5;

            % MPC Controller Validation

            %Change x0 values later
            x0 = [2; 1; pi/2];
            u0 = [0;0];
            validateFcns(MPCController, x0,u0,[],{Ts});
            try
                x_t = app.path(1,end);
                y_t = app.path(2,end);
                theta_t = app.path(3,end);

                terminal_path = zeros(3,20);
                terminal_path(1,:) = x_t;
                terminal_path(2,:) = y_t;
                terminal_path(3,:) = theta_t;

                q = [app.path terminal_path];
                %q = q(:,1:4:end); % Downsample q
                xref = zeros(nx,size(q,2));
                q = q';
                
                xref = zeros(size(q,1),2);
                xref(:,1) = q(:,1);
                xref(:,2) = q(:,2);
                xref(:,3) = q(:,3);
                t = 0:Ts:Ts*(size(q,1)-1);
                %t = 0:Ts:Ts*(size(q,1)-1);
                t = t';
            catch
                error("Failed to generate MPC.");
            end
            % Simulate MPC Controller
            options = nlmpcmoveopt;
            options.Parameters = {Ts};
            x = x0;
            u = u0;
            xHistory = x;
            uHistory = u;
            trajectorylookahead = 5;
            xref = [xref; xref(end,:); xref(end,:); xref(end,:); xref(end,:); xref(end,:)];
            
            [coreData, onlineData] = getCodeGenerationData(MPCController, x0, u0, {Ts});
            
            tic;
            for k = 1:length(t)
                % Update state reference
                onlineData.ref = xref(k:k+trajectorylookahead,:);
            
                % Compute MPC
                [u, onlineData] = nlmpcmoveCodeGeneration(coreData, x, u, onlineData);
            
                % Apply optimal inputs and simulate next timestep
                x = UnicycleDiscrete(x,u,Ts);
            
                % Save States and Input Histories
                xHistory = [xHistory x];
                uHistory = [uHistory u];
            end

            % Codegen Block
            codeGenEnabled = 0;
            if(codeGenEnabled)
                func = 'nlmpcmoveCodeGeneration';
                funcOutput = 'nlmpcmoveMEX';
                Cfg = coder.config("lib");
                Cfg.TargetLang = 'C';
                Cfg.DynamicMemoryAllocation = 'off';
                codegen('-config',Cfg,func,'-o',funcOutput,'-args',{coder.Constant(coreData),x,u,onlineData});
            end
            
            MPCDataPkg = zeros(size(uHistory));

            for i = 1:1:size(uHistory,2)

                inputs = [uHistory(1,i), uHistory(2,i)];

                % Convert rad/s to pulses per 10 ms
                wheelPPS = floor((1120 * (inputs/(2*pi())))/100); 
                % We have 1120 pulses per revolution
    
                % Figure out how to transfer data through serial port
                MPCDataPkg(:,i) = wheelPPS;
            end

            app.automatedDataPkg = MPCDataPkg;           
            

            disp("Average MPC Iteration Time (ms): ");
            disp(toc/k * 1000);
            xHistory(:,1) = [];
            uHistory(:,1) = [];
            % Update State and Input plots
            hold(app.ControlPlot);
            stairs(app.ControlPlot,t,uHistory(1,:));
            stairs(app.ControlPlot,t,uHistory(2,:));
            legend(app.ControlPlot,["Planned \omega_{L} [rad/s]","Planned \omega_{R} [rad/s]"],'Location','southeast');


            hold(app.StatePlot);
            plot(app.StatePlot,t,xHistory(1,:));
            plot(app.StatePlot,t,xHistory(2,:));
            plot(app.StatePlot,t,xHistory(3,:));
            legend(app.StatePlot,["Planned X","Planned Y","Planned \theta"],'Location','southeast');

            set(app.UIFigure, 'currentaxes', app.RobotEnvironmentPlot);
            
            hold(app.RobotEnvironmentPlot);

            arrowLength = 0.75;

            plot(app.RobotEnvironmentPlot,xHistory(1,:),xHistory(2,:),'b--','LineWidth',2, 'DisplayName','Planned Trajectory');
            %plot(app.RobotEnvironmentPlot,xHistory(1,1),xHistory(2,1),'r.',"MarkerSize",30);
            [startX, startY] = GenerateRobot(xHistory(1,1), xHistory(2,1), app.robotLength, app.robotWidth, xHistory(3,1));
            [endX, endY] = GenerateRobot(xHistory(1,end), xHistory(2,end), app.robotLength, app.robotWidth, xHistory(3,end));
            
            arrowXStart = xHistory(1,1)+arrowLength*cos(xHistory(3,1));          % X coordinate of arrow end
            arrowYStart = xHistory(2,1)+arrowLength*sin(xHistory(3,1));
            fill(app.RobotEnvironmentPlot, startX, startY,'r','DisplayName', 'Start Pose')
            plot(app.RobotEnvironmentPlot, [xHistory(1,1), arrowXStart], [xHistory(2,1), arrowYStart], 'Color', 'r', 'LineWidth',3,'DisplayName', 'Start Orientation');
             
            arrowXEnd = xHistory(1,end)+arrowLength*cos(xHistory(3,end));
            arrowYEnd = xHistory(2,end)+arrowLength*sin(xHistory(3,end));
            %plot(app.RobotEnvironmentPlot,xHistory(1,end),xHistory(2,end),'g.',"MarkerSize",30);
            fill(app.RobotEnvironmentPlot, endX, endY, 'g', 'DisplayName', 'Goal Pose')
            plot(app.RobotEnvironmentPlot, [xHistory(1,end), arrowXEnd], [xHistory(2,end), arrowYEnd], 'Color', 'g', 'LineWidth',3,'DisplayName','End Orientation');
            

            app.RobotEnvironmentPlot.XGrid = "on";
            app.RobotEnvironmentPlot.YGrid = "on";
            app.RobotEnvironmentPlot.GridColor = 'k';
            app.RobotEnvironmentPlot.GridAlpha = 0.85;
            app.RobotEnvironmentPlot.Title.String = "Robot Environment";
            legend(app.RobotEnvironmentPlot);
            
        end
        
        function odometryTimeCalc(app)
            % Create a timer object that executes the computation every 0.5 second
            app.odometryCalcTimeObject = timer('ExecutionMode', 'fixedRate', 'Period', 0.5, 'TimerFcn', @calcOdometry);
            
            % Start the timer
            start(app.odometryCalcTimeObject);

            app.xActual = 0;
            app.yActual = 0;
            app.thetaActual = 0;
            
            function calcOdometry(~,~)

                r_wheel = 0.030; % Wheel Radius [m]
                d_wheel = 0.100; % Distance Between Centroid and Wheel [m]

                scaling_factor_translation = 1.85;
                scaling_factor_rotation = 1.66;

                % State Estimation Calculation
                distanceL = (app.leftWheelPulse/1120)*(3.14159265*2*r_wheel);
                distanceR = (app.rightWheelPulse/1120)*(3.14159265*2*r_wheel);
                

                translation = scaling_factor_translation * ((distanceR-distanceL)/2);

                deltaTheta = scaling_factor_rotation * ((distanceR-distanceL)/(2*d_wheel));

                app.xActual = app.xActual + (translation * cos(app.thetaActual+(deltaTheta/2)));
                app.yActual = app.yActual + (translation * sin(app.thetaActual+(deltaTheta/2)));
                app.thetaActual = app.thetaActual + deltaTheta;

                % Idk if this is correct
                app.timeActual = app.count;

            end
        end


        % function odometryCalc(app)
        %     try
        %         receivedData = app.robotData;
        % 
        % 
        %         count = receivedData(6);
        % 
        %         r_wheel = 0.030; % Wheel Radius [m]
        %         d_wheel = 0.100; % Distance Between Centroid and Wheel [m]
        % 
        %         scaling_factor_translation = 1.85;
        %         scaling_factor_rotation = 1.66;
        % 
        %         % State Estimation Calculation
        %         distanceL = (app.leftWheelPulse/1120)*(3.14159265*2*r_wheel);
        %         distanceR = (app.rightWheelPulse/1120)*(3.14159265*2*r_wheel);
        % 
        % 
        %         translation = scaling_factor_translation * ((distanceR-distanceL)/2);
        % 
        %         deltaTheta = scaling_factor_rotation * ((distanceR-distanceL)/(2*d_wheel));
        % 
        %         app.xActual = app.xActual + (translation * cos(app.thetaActual+(deltaTheta/2)));
        %         app.yActual = app.yActual + (translation * sin(app.thetaActual+(deltaTheta/2)));
        %         app.thetaActual = app.thetaActual + deltaTheta;
        % 
        %         % Idk if this is correct
        %         app.timeActual = count;
        % 
        %     catch
        %         error("Could not calculate odometry")
        %     end
        % end

        function controlTimePlot(app)
            
            app.controlTimeObject = timer('ExecutionMode', 'fixedRate', 'Period', 0.5, 'TimerFcn', @controlPlot);
            
            % Start the timer
            start(app.controlTimeObject);
            plotTime = 0;
            %leftOmega = {};
            %rightOmega = {};
            %time = {};

            %leftPlot = plot(app.ControlPlot,NaN, NaN,'o', 'Color',[0 0.4470 0.7410],'DisplayName','Actual \omega_{L} [rad/s]');
            %rightPlot = plot(app.ControlPlot,NaN, NaN,'o', 'Color',[0.6350 0.0780 0.1840],'DisplayName','Actual \omega_{R} [rad/s]');
            function controlPlot(~,~)
                % Updating Control Plot
                %rightOmega = [rightOmega,(2*pi*100*app.rightWheelPulse)/1120];
                %leftOmega = [leftOmega,(2*pi*100*app.leftWheelPulse)/1120];


                %time = [time, app.timeActual];
                rightOmega = (2*pi*100*app.rightWheelPulse)/1120;
                leftOmega = (2*pi*100*app.leftWheelPulse)/1120;
                hold(app.ControlPlot, "on");
                
                plot(app.ControlPlot,plotTime,leftOmega,'o', 'Color',[0 0.4470 0.7410],'DisplayName','Actual \omega_{L} [rad/s]');
                plot(app.ControlPlot,plotTime,rightOmega,'o', 'Color',[0.6350 0.0780 0.1840],'DisplayName','Actual \omega_{R} [rad/s]');
                
                plotTime = plotTime + 0.5;
                %set(leftPlot, 'XData',time)
                %set(rightPlot, 'XData',time)
                %set(leftPlot, 'YData',leftOmega)
                %set(rightPlot, 'YData',rightOmega)
                %drawnow;

            end
            
        end

        function trajectoryPlot(app)
            try
                xPos = app.xActual;
                yPos = app.yActual;
                theta = app.thetaActual;
                time = app.timeActual;

                % Updating Input Plot
                % Calculating rad/s from pulses
                rightOmega = (2*pi*100*app.rightWheelPulse)/1120;
                leftOmega = (2*pi*100*app.leftWheelPulse)/1120;
                
                % Updating Control Plot
                hold(app.ControlPlot,'on');
                plot(app.ControlPlot,time,leftOmega,'.', 'Color',[0 0.4470 0.7410],'DisplayName','Actual \omega_{L} [rad/s]');
                plot(app.ControlPlot,time,rightOmega,'.', 'Color',[0.6350 0.0780 0.1840],'DisplayName','Actual \omega_{R} [rad/s]');
                app.ControlPlot.YLim = [-40 40];

                % Updating State Plot
                % hold(app.StatePlot);
                % %stateXLine = line(nan, nan);
                % %xStateXdata = get(stateXLine, 'XData');
                % %xStateTimeData = get(stateXLine, 'YData');
                % %stateYLine = line(nan, nan);
                % %stateThetaLine = line(nan, nan);
                % plot(app.StatePlot,time,xPos,'.', 'Color',[0 0.4470 0.7410],'DisplayName','Actual X');
                % plot(app.StatePlot,time,yPos,'.', 'Color',[0.6350 0.0780 0.1840],'DisplayName','Actual Y');
                % plot(app.StatePlot,time,theta,'.', 'Color',[0.9290 0.6940 0.1250],'DisplayName','Actual Theta');
                % 
                % % Updating X Y Trajectory
                % %hold(app.RobotEnvironmentPlot);
                % %trajectoryLine = line(nan, nan);
                % %X = get(trajectoryLine, 'XData');
                % %Y = get(trajectoryLine, 'YData');
                % app.RobotEnvironmentPlot.XLim = [-app.OccupancyGridWidth/2 app.OccupancyGridWidth/2];
                % app.RobotEnvironmentPlot.YLim = [-app.OccupancyGridWidth/2 app.OccupancyGridWidth/2];
                % 
                % 
                % [newX, newY] = GenerateRobot(xPos, yPos, app.robotLength, app.robotWidth, theta);
                % 
                % fill(app.RobotEnvironmentPlot, newX, newY,'black')

            catch
                error("Could not live update trajectory")
            end
        end

        % function mpcSimulator(app)
        %     try
        %         M = app.estimatedTravelTime/0.1;
        % 
        %         % Initialize
        %         xOpt = zeros(3, M+1);
        %         uOpt = zeros(2, M);
        %         x0 = [app.CurrPoseX; app.CurrPoseY; app.CurrPoseTheta];
        %         xOpt(:,1) = x0;
        % 
        %         for i = 1:1:M
        %             % Call MPC function starting with XOpt at the current
        %             % time 
        %             % Replace line in MPCPlanner code for the x0 values,
        %             % with app.CurrPoseX, app.CurrPoseY, app.CurrPoseTheta
        %             MPCPlanner(app);
        % 
        %             % Save closed loop trajectory
        %             xOpt(:,i+1) = app.optimalState;
        %             uOpt(:,i) = [app.omegaL;app.omegaR];
        %         end
        % 
        %     catch
        %         error("Failed to execute trajectory");
        %     end
        % end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SearchforCOMPortsButton
        function SearchforCOMPortsButtonPushed(app, event)
            com = serialportlist();
            app.comPortList = com;
            value = app.COMPortsListBox.Value;
            app.COMPortsListBox.Items = com;
        end

        % Clicked callback: COMPortsListBox
        function COMPortsListBoxClicked(app, event)
            item = event.InteractionInformation.Item;
            app.selectedCOMPortIndex = item;
            app.ConnectLamp.Color = "y";
            app.ConnecttoCOMPortButton.Enable = "on";
        end

        % Button pushed function: ConnecttoCOMPortButton
        function ConnecttoCOMPortButtonPushed(app, event)
            target = app.comPortList(app.selectedCOMPortIndex);
            app.selectedCOMPort = target;
            serialObj = serialport(target,115200);
            app.SerialObject = serialObj;
            app.ConnectLamp.Color = 'g';
            app.DisconnectfromCOMPortButton.Enable = "on";
            readSerialComTime(app);
        end

        % Button pushed function: DisconnectfromCOMPortButton
        function DisconnectfromCOMPortButtonPushed(app, event)
            stop(app.writeTimeObject);
            stop(app.readTimeObject);
            delete(app.writeTimeObject);
            delete(app.readTimeObject);
            delete(app.SerialObject);
            app.ConnectLamp.Color = 'r';
        end

        % Button pushed function: ActiviateManualControlButton
        function ActiviateManualControlButtonPushed(app, event)
            app.KeyboardControlLamp.Color = 'g';
            app.ActivateAutonomousControlButton.Enable = "off";
            app.driveMode = 1;
            app.StopManualControlButton.Enable = "on";
            odometryTimeCalc(app);
            controlTimePlot(app);
            %app.ActiviateManualControlButton.Enable = "off";
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if app.driveMode == 1
                if key == "uparrow"
                    % Increase RPM by 5 for both wheels
                    app.omegaL = app.omegaL + 5;
                    app.omegaR = app.omegaR + 5;
                    if app.omegaL < -200
                        app.omegaL = -200;
                    elseif app.omegaL > 200
                        app.omegaL = 200;
                    else
                    end

                    if app.omegaR < -200
                        app.omegaR = -200;
                    elseif app.omegaR > 200
                        app.omegaR = 200;
                    else
                    end

                    app.LeftWheelRPMGauge.Value = app.omegaL;
                    app.RightWheelRPMGauge.Value = app.omegaR;
                elseif key == "downarrow"
                    % Decrease RPM by 5 for both wheels
                    app.omegaL = app.omegaL - 5;
                    app.omegaR = app.omegaR - 5;
                    if app.omegaL < -200
                        app.omegaL = -200;
                    elseif app.omegaL > 200
                        app.omegaL = 200;
                    else
                    end

                    if app.omegaR < -200
                        app.omegaR = -200;
                    elseif app.omegaR > 200
                        app.omegaR = 200;
                    else
                    end
                    app.LeftWheelRPMGauge.Value = app.omegaL;
                    app.RightWheelRPMGauge.Value = app.omegaR;
                elseif key == "rightarrow"
                    % Increase RPM by 2.5 for left wheel and decrease RPM
                    % by 2.5 for right wheel
                    app.omegaL = app.omegaL - 5;
                    app.omegaR = app.omegaR + 5;
                    if app.omegaL < -200
                        app.omegaL = -200;
                    elseif app.omegaL > 200
                        app.omegaL = 200;
                    else
                    end

                    if app.omegaR < -200
                        app.omegaR = -200;
                    elseif app.omegaR > 200
                        app.omegaR = 200;
                    else
                    end
                    app.LeftWheelRPMGauge.Value = app.omegaL;
                    app.RightWheelRPMGauge.Value = app.omegaR;
                elseif key == "leftarrow"
                    % Increase RPM by 2.5 for right wheel and decrease RPM
                    % by 2.5 for left wheel
                    app.omegaL = app.omegaL + 5;
                    app.omegaR = app.omegaR - 5;
                    if app.omegaL < -200
                        app.omegaL = -200;
                    elseif app.omegaL > 200
                        app.omegaL = 200;
                    else
                    end

                    if app.omegaR < -200
                        app.omegaR = -200;
                    elseif app.omegaR > 200
                        app.omegaR = 200;
                    else
                    end
                    app.LeftWheelRPMGauge.Value = app.omegaL;
                    app.RightWheelRPMGauge.Value = app.omegaR;
                end

                rightWheelRPM = app.omegaR;
                leftWheelRPM = app.omegaL;
                % Convert RPM to pulses per 10 millisecond
                % We have 1120 pulses per revolution

                rightWheelPPS= floor((1120*(rightWheelRPM/60))/100);
                leftWheelPPS = floor((1120*(leftWheelRPM/60))/100);

                % Writes manual data onto serial object
                app.manualDataPkg = [leftWheelPPS, rightWheelPPS];
                writeManualSerial(app);

            end
            
        end

        % Button pushed function: StopManualControlButton
        function StopManualControlButtonPushed(app, event)
            app.KeyboardControlLamp.Color = 'r';
            app.LeftWheelRPMGauge.Value = 0;
            app.RightWheelRPMGauge.Value = 0;
            app.omegaL = 0;
            app.omegaR = 0;
            write(app.SerialObject, [0, 0], 'int8')
            stop(app.odometryCalcTimeObject)
            stop(app.controlTimeObject)
            app.ActivateAutonomousControlButton.Enable = "on";
        end

        % Button pushed function: ActivateAutonomousControlButton
        function ActivateAutonomousControlButtonPushed(app, event)
            app.ActiviateManualControlButton.Enable = "off";
            app.PlanTrajectoryButton.Enable = "on";
            app.ActivateAutonomousControlButton.Enable = "off";
            app.driveMode = 0;

        end

        % Button pushed function: PlanTrajectoryButton
        function PlanTrajectoryButtonPushed(app, event)
            if app.driveMode == 0
                createOccupancyGrid(app);
                show(app.occupancyGrid)
                pause(1);
                h = msgbox('Please select the target using the left mouse button');
                uiwait(h,5);
                if ishandle(h) == 1
                    delete(h);
                end
                xlabel('Please select the target using the left mouse button', 'Color', 'black');
                but = 0;
                while (but ~= 1) %Repeat until the Left button is not clicked
                    [xval,yval,but]=ginput(1);
                end
                close all
                xval = round(xval, 2);
                yval = round(yval, 2);
                
                app.GoalPoseX.Value = xval;
                app.GoalPoseY.Value = yval;
                app.GoalPoseTheta.Value = 0;
    
                pathPlanning(app);
                MPCPlanner(app);
                app.TrajectoryStatusLamp.Color = 'g';
                app.ExecuteTrajectoryButton.Enable = "on";
            end
        end

        % Button pushed function: ExecuteTrajectoryButton
        function ExecuteTrajectoryButtonPushed(app, event)
            %mpcSimulator(app);
            hold(app.ControlPlot, "on");
            controlTimePlot(app);
            writeAutomatedSerial(app);
            %odometryCalc(app);
            
            stop(app.controlTimeObject)

        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            % Reset buttons and lights to default state
            app.DisconnectfromCOMPortButton.Enable = "off";
            app.ActivateAutonomousControlButton.Enable = "on";
            app.PlanTrajectoryButton.Enable = "off";
            app.TrajectoryStatusLamp.Color = 'r';
            app.ExecuteTrajectoryButton.Enable = "off";
            stop(app.writeTimeObject);
            stop(app.readTimeObject);
            stop(app.odometryCalcTimeObject);
            stop(app.writeAutomateTimeObject);
            stop(app.controlTimeObject);
            delete(app.writeTimeObject);
            delete(app.readTimeObject);

            app.ActiviateManualControlButton.Enable = "on";
            app.StopManualControlButton.Enable = "off";
            app.KeyboardControlLamp.Color = 'r';
            %manualSerialCom(app);   

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {608, 608};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {516, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.RobotEnvironmentPlot = uiaxes(app.GridLayout2);
            title(app.RobotEnvironmentPlot, 'Robot Environment')
            xlabel(app.RobotEnvironmentPlot, 'X [m]')
            ylabel(app.RobotEnvironmentPlot, 'Y [m]')
            zlabel(app.RobotEnvironmentPlot, 'Z')
            app.ResetOdometryButton = uibutton(app.GridLayout2, 'push');
            app.ResetOdometryButton.Text = 'Reset Odometry';
            app.ResetOccupancyGridButton = uibutton(app.GridLayout2, 'push');
            app.ResetOccupancyGridButton.Text = 'Reset Occupancy Grid';
            app.SetDataloggingLocationButton = uibutton(app.GridLayout2, 'push');
            app.SetDataloggingLocationButton.Text = 'Set Datalogging Location';
            app.STOPButton = uibutton(app.GridLayout2, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.CSVLocationEditFieldLabel = uilabel(app.GridLayout2);
            app.CSVLocationEditFieldLabel.Text = 'CSV Location';
            app.CSVLocationEditField = uieditfield(app.GridLayout2, 'text');
            app.CSVLocationEditField.Enable = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.StatePlot = uiaxes(app.RightPanel);
            title(app.StatePlot, 'State Variables')
            xlabel(app.StatePlot, 'Time [s]')
            zlabel(app.StatePlot, 'Z')
            app.ControlPlot = uiaxes(app.RightPanel);
            title(app.ControlPlot, 'Control Inputs')
            xlabel(app.ControlPlot, 'Time [s]')
            zlabel(app.ControlPlot, 'Z')
            app.TabGroup = uitabgroup(app.RightPanel);
            app.SerialPortTab = uitab(app.TabGroup);
            app.SerialPortTab.Title = 'Serial Port';
            app.SearchforCOMPortsButton = uibutton(app.SerialPortTab, 'push');
            app.SearchforCOMPortsButton.ButtonPushedFcn = createCallbackFcn(app, @SearchforCOMPortsButtonPushed, true);
            app.SearchforCOMPortsButton.Text = 'Search for COM Ports';
            app.ConnecttoCOMPortButton = uibutton(app.SerialPortTab, 'push');
            app.ConnecttoCOMPortButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoCOMPortButtonPushed, true);
            app.ConnecttoCOMPortButton.Enable = 'off';
            app.ConnecttoCOMPortButton.Text = 'Connect to COM Port';
            app.TextArea = uitextarea(app.SerialPortTab);
            app.TextArea.Value = {'1. Click on "Search for COM Ports" button once your ESP32 is plugged in'; '2. Select the correct COM Channel your ESP32 is connect'; '3. Click "Connect to COM Port". Light will flash green once connected'; '4. Click "Disconnect from COM Port" to disconnect.'};
            app.COMPortsListBoxLabel = uilabel(app.SerialPortTab);
            app.COMPortsListBoxLabel.Text = {'COM'; 'Ports'};
            app.COMPortsListBox = uilistbox(app.SerialPortTab);
            app.COMPortsListBox.ClickedFcn = createCallbackFcn(app, @COMPortsListBoxClicked, true);
            app.ConnectLamp = uilamp(app.SerialPortTab);
            app.DisconnectfromCOMPortButton = uibutton(app.SerialPortTab, 'push');
            app.DisconnectfromCOMPortButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectfromCOMPortButtonPushed, true);
            app.DisconnectfromCOMPortButton.Enable = 'off';
            app.DisconnectfromCOMPortButton.Text = 'Disconnect from COM Port';
            app.AutonomousControlTab = uitab(app.TabGroup);
            app.AutonomousControlTab.Title = 'Autonomous Control';
            app.PlanTrajectoryButton = uibutton(app.AutonomousControlTab, 'push');
            app.PlanTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @PlanTrajectoryButtonPushed, true);
            app.PlanTrajectoryButton.Enable = 'off';
            app.PlanTrajectoryButton.Text = 'Plan Trajectory';
            app.ExecuteTrajectoryButton = uibutton(app.AutonomousControlTab, 'push');
            app.ExecuteTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteTrajectoryButtonPushed, true);
            app.ExecuteTrajectoryButton.Enable = 'off';
            app.ExecuteTrajectoryButton.Text = 'Execute Trajectory';
            app.XEditFieldLabel = uilabel(app.AutonomousControlTab);
            app.XEditFieldLabel.Text = 'X';
            app.GoalPoseX = uieditfield(app.AutonomousControlTab, 'numeric');
            app.YEditFieldLabel = uilabel(app.AutonomousControlTab);
            app.YEditFieldLabel.Text = 'Y';
            app.GoalPoseY = uieditfield(app.AutonomousControlTab, 'numeric');
            app.ThetaLabel = uilabel(app.AutonomousControlTab);
            app.ThetaLabel.Text = 'Theta';
            app.GoalPoseTheta = uieditfield(app.AutonomousControlTab, 'numeric');
            app.GoalPoseLabel = uilabel(app.AutonomousControlTab);
            app.GoalPoseLabel.Text = 'Goal Pose';
            app.TrajectoryStatusLampLabel = uilabel(app.AutonomousControlTab);
            app.TrajectoryStatusLampLabel.Text = 'Trajectory Status';
            app.TrajectoryStatusLamp = uilamp(app.AutonomousControlTab);
            app.CurrentPoseLabel = uilabel(app.AutonomousControlTab);
            app.CurrentPoseLabel.Text = 'Current Pose';
            app.thetaLabel = uilabel(app.AutonomousControlTab);
            app.thetaLabel.Text = 'Theta';
            app.CurrPoseTheta = uieditfield(app.AutonomousControlTab, 'numeric');
            app.CurrPoseTheta.Enable = 'off';
            app.YEditField_2Label = uilabel(app.AutonomousControlTab);
            app.YEditField_2Label.Text = 'Y';
            app.CurrPoseY = uieditfield(app.AutonomousControlTab, 'numeric');
            app.CurrPoseY.Enable = 'off';
            app.XEditField_2Label = uilabel(app.AutonomousControlTab);
            app.XEditField_2Label.Text = 'X';
            app.CurrPoseX = uieditfield(app.AutonomousControlTab, 'numeric');
            app.CurrPoseX.Enable = 'off';
            app.ActivateAutonomousControlButton = uibutton(app.AutonomousControlTab, 'push');
            app.ActivateAutonomousControlButton.ButtonPushedFcn = createCallbackFcn(app, @ActivateAutonomousControlButtonPushed, true);
            app.ActivateAutonomousControlButton.Text = {'Activate Autonomous '; 'Control'};
            app.ManualControlTab = uitab(app.TabGroup);
            app.ManualControlTab.Title = 'Manual Control';
            app.ActiviateManualControlButton = uibutton(app.ManualControlTab, 'push');
            app.ActiviateManualControlButton.ButtonPushedFcn = createCallbackFcn(app, @ActiviateManualControlButtonPushed, true);
            app.ActiviateManualControlButton.Text = {'Activiate'; 'Manual Control'};
            app.KeyboardControlLampLabel = uilabel(app.ManualControlTab);
            app.KeyboardControlLampLabel.Text = 'Keyboard Control';
            app.KeyboardControlLamp = uilamp(app.ManualControlTab);
            app.StopManualControlButton = uibutton(app.ManualControlTab, 'push');
            app.StopManualControlButton.ButtonPushedFcn = createCallbackFcn(app, @StopManualControlButtonPushed, true);
            app.StopManualControlButton.Enable = 'off';
            app.StopManualControlButton.Text = {'Stop'; 'Manual Control'};
            app.LeftWheelRPMGaugeLabel = uilabel(app.ManualControlTab);
            app.LeftWheelRPMGaugeLabel.Text = {'Left Wheel'; 'RPM'};
            app.LeftWheelRPMGauge = uigauge(app.ManualControlTab, 'circular');
            app.LeftWheelRPMGauge.Limits = [-200 200];
            app.LeftWheelRPMGauge.MajorTicks = [-200 -120 -40 0 40 120 200];
            app.LeftWheelRPMGauge.MinorTicks = [-200 -180 -160 -140 -120 -100 -80 -60 -40 -20 0 20 40 60 80 100 120 140 160 180 200];
            app.RightWheelRPMGaugeLabel = uilabel(app.ManualControlTab);
            app.RightWheelRPMGaugeLabel.Text = {'Right Wheel'; 'RPM'};
            app.RightWheelRPMGauge = uigauge(app.ManualControlTab, 'circular');
            app.RightWheelRPMGauge.Limits = [-200 200];
            app.RightWheelRPMGauge.MajorTicks = [-200 -120 -40 0 40 120 200];
            app.RightWheelRPMGauge.MinorTicks = [-200 -180 -160 -140 -120 -100 -80 -60 -40 -20 0 20 40 60 80 100 120 140 160 180 200];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MobileRobotControlGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end