classdef PlottingTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        BytesAvailableEditField       matlab.ui.control.NumericEditField
        BytesAvailableEditFieldLabel  matlab.ui.control.Label
        COMPortsListBox               matlab.ui.control.ListBox
        COMPortsListBoxLabel          matlab.ui.control.Label
        DisconnectfromCOMPortButton   matlab.ui.control.Button
        ConnectLamp                   matlab.ui.control.Lamp
        ConnecttoCOMPortButton        matlab.ui.control.Button
        SearchforCOMPortsButton       matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        SerialObject % Serial Port Object
        PlotData;
        lastData;
        currentTime = 0;
        readSerialTimerObject;
        comPortList;
        selectedCOMPortIndex;
        selectedCOMPort;
        bytesAvailable;
        plotDataTimerObject;
    end
    
    methods (Access = private)
        
        function readSerialCB(app)
            app.readSerialTimerObject = timer('ExecutionMode', 'fixedRate','Name','ReadSerial','BusyMode','drop', 'Period', 0.05, 'TimerFcn', @readSerial);
            
            function readSerial(~,~)
                app.lastData = str2double(readline(app.SerialObject));
                app.BytesAvailableEditField.Value = app.SerialObject.NumBytesAvailable;
            end
        end

        function plotUpdateCB(app)

            app.plotDataTimerObject = timer('ExecutionMode', 'fixedDelay','Name','Plot','BusyMode','drop', 'Period', 0.1, 'TimerFcn', @plotUpdate);
            
            function plotUpdate(~,~)
                xSeries = [get(app.PlotData,'XData') app.currentTime];
                ySeries = [get(app.PlotData,'YData') app.lastData];
                set(app.PlotData,'XData',xSeries,'YData',ySeries);
                drawnow;
                windowSize = 50;
                app.UIAxes.XLim = [app.currentTime - windowSize, app.currentTime];
                app.currentTime = app.currentTime + 0.1;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PlotData = plot(app.UIAxes, 0, 0,'.');
            app.UIAxes.Interactions = [];
            app.UIAxes.PickableParts = 'none';
            app.UIAxes.XLimitMethod = 'tight';
            readSerialCB(app);
            plotUpdateCB(app);
        end

        % Button pushed function: SearchforCOMPortsButton
        function SearchforCOMPortsButtonPushed(app, event)
            com = serialportlist();
            app.comPortList = com;
            app.COMPortsListBox.Items = com;
        end

        % Button pushed function: ConnecttoCOMPortButton
        function ConnecttoCOMPortButtonPushed(app, event)
            target = app.comPortList(app.selectedCOMPortIndex);
            app.selectedCOMPort = target;
            serialObj = serialport(target,115200);
            app.SerialObject = serialObj;
            app.ConnectLamp.Color = 'g';
            app.DisconnectfromCOMPortButton.Enable = "on";
            start(app.readSerialTimerObject);
            start(app.plotDataTimerObject);
        end

        % Button pushed function: DisconnectfromCOMPortButton
        function DisconnectfromCOMPortButtonPushed(app, event)
            stop(app.readSerialTimerObject);
            stop(app.plotDataTimerObject);
            delete(app.SerialObject);
            app.ConnectLamp.Color = 'r';
            
            % Search for COM Ports again and allows you to restart the
            % choosing process
            app.ConnecttoCOMPortButton.Enable = "off";
            SearchforCOMPortsButtonPushed(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                stop(timerfindall)
                delete(timerfindall)  
                delete(app)
            catch
                delete(app)
            end
            
        end

        % Clicked callback: COMPortsListBox
        function COMPortsListBoxClicked(app, event)
            item = event.InteractionInformation.Item;
            app.selectedCOMPortIndex = item;
            app.ConnectLamp.Color = "y";
            app.ConnecttoCOMPortButton.Enable = "on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Data Recieved')
            xlabel(app.UIAxes, 'Time [s]')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SearchforCOMPortsButton = uibutton(app.UIFigure, 'push');
            app.SearchforCOMPortsButton.ButtonPushedFcn = createCallbackFcn(app, @SearchforCOMPortsButtonPushed, true);
            app.SearchforCOMPortsButton.Text = 'Search for COM Ports';
            app.ConnecttoCOMPortButton = uibutton(app.UIFigure, 'push');
            app.ConnecttoCOMPortButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoCOMPortButtonPushed, true);
            app.ConnecttoCOMPortButton.Enable = 'off';
            app.ConnecttoCOMPortButton.Text = 'Connect to COM Port';
            app.ConnectLamp = uilamp(app.UIFigure);
            app.DisconnectfromCOMPortButton = uibutton(app.UIFigure, 'push');
            app.DisconnectfromCOMPortButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectfromCOMPortButtonPushed, true);
            app.DisconnectfromCOMPortButton.Enable = 'off';
            app.DisconnectfromCOMPortButton.Text = 'Disconnect from COM Port';
            app.COMPortsListBoxLabel = uilabel(app.UIFigure);
            app.COMPortsListBoxLabel.Text = {'COM'; 'Ports'};
            app.COMPortsListBox = uilistbox(app.UIFigure);
            app.COMPortsListBox.ClickedFcn = createCallbackFcn(app, @COMPortsListBoxClicked, true);
            app.BytesAvailableEditFieldLabel = uilabel(app.UIFigure);
            app.BytesAvailableEditFieldLabel.Text = 'Bytes Available';
            app.BytesAvailableEditField = uieditfield(app.UIFigure, 'numeric');
            app.BytesAvailableEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlottingTest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end